classdef Model_Fitting_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadMenu_2                      matlab.ui.container.Menu
        DataMenu                        matlab.ui.container.Menu
        AxesSeparateMenu                matlab.ui.container.Menu
        GathetTwFileNamesMenu           matlab.ui.container.Menu
        LoadProbeAxisMenu_2             matlab.ui.container.Menu
        MATLABReadyFormatMenu           matlab.ui.container.Menu
        SaveMenu_2                      matlab.ui.container.Menu
        DataMenu_2                      matlab.ui.container.Menu
        MATLABReadyFormatMenu_2         matlab.ui.container.Menu
        ManuscriptMenu                  matlab.ui.container.Menu
        ModelFittingManuscriptWebpageMenu  matlab.ui.container.Menu
        CiteMenu                        matlab.ui.container.Menu
        GitHubMenu                      matlab.ui.container.Menu
        ModelFittingGitHubWebpageMenu   matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        YoutubeTutorialsMenu            matlab.ui.container.Menu
        Tutorial1                       matlab.ui.container.Menu
        Tutorial2                       matlab.ui.container.Menu
        Tutorial3                       matlab.ui.container.Menu
        Tutorial4                       matlab.ui.container.Menu
        Tutorial5                       matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        DataTab                         matlab.ui.container.Tab
        LoadingLampLabel                matlab.ui.control.Label
        LoadingLamp                     matlab.ui.control.Lamp
        MATLABReadyFormatPanel          matlab.ui.container.Panel
        SaveDataButton                  matlab.ui.control.Button
        LoadDataButton                  matlab.ui.control.Button
        LoadDataFromdatortxtFormatPanel  matlab.ui.container.Panel
        TextArea_3                      matlab.ui.control.TextArea
        LoadTwFilesButton               matlab.ui.control.Button
        CompleteLoadingButton           matlab.ui.control.Button
        GatherWaitingTimeFileNamesButton  matlab.ui.control.Button
        TwUnitsDropDownLabel            matlab.ui.control.Label
        TwUnitsDropDown                 matlab.ui.control.DropDown
        TwColumnDropDownLabel           matlab.ui.control.Label
        TwColumnDropDown                matlab.ui.control.DropDown
        OrientationDropDownLabel        matlab.ui.control.Label
        OrientationDropDown             matlab.ui.control.DropDown
        PumpAxisPanel_2                 matlab.ui.container.Panel
        VerifiedCheckBox                matlab.ui.control.CheckBox
        RotatingFramecm1Spinner_2Label  matlab.ui.control.Label
        RotatingFramecm1Spinner_2       matlab.ui.control.Spinner
        TimeSteppsSpinnerLabel          matlab.ui.control.Label
        TimeSteppsSpinner               matlab.ui.control.Spinner
        ProbeAxisPanel_2                matlab.ui.container.Panel
        GenerateProbeAxisButton         matlab.ui.control.Button
        FirstPixelcm1SpinnerLabel       matlab.ui.control.Label
        FirstPixelcm1Spinner            matlab.ui.control.Spinner
        PixelStepSizecm1SpinnerLabel    matlab.ui.control.Label
        PixelStepSizecm1Spinner         matlab.ui.control.Spinner
        LoadProbeAxisFileButton         matlab.ui.control.Button
        Tw_files_table                  matlab.ui.control.Table
        MaskandInverseVarianceTab       matlab.ui.container.Tab
        PumpAxisFittingRangePanel       matlab.ui.container.Panel
        MinLabel                        matlab.ui.control.Label
        Pump_mask_dd1                   matlab.ui.control.DropDown
        MaxLabel_2                      matlab.ui.control.Label
        Pump_mask_dd2                   matlab.ui.control.DropDown
        WaitingTimeAxisFittingRangeandInverseVariancePanel  matlab.ui.container.Panel
        InvVarTwTable                   matlab.ui.control.Table
        LoadInvVarFromFileButton        matlab.ui.control.Button
        ProbeAxisFittingRangePanel      matlab.ui.container.Panel
        MinLabel_2                      matlab.ui.control.Label
        Probe_mask_dd1                  matlab.ui.control.DropDown
        MaxLabel                        matlab.ui.control.Label
        Probe_mask_dd2                  matlab.ui.control.DropDown
        TrimData                        matlab.ui.control.Button
        NumberofPointsEditFieldLabel    matlab.ui.control.Label
        NumberofPointsEditField         matlab.ui.control.NumericEditField
        TextArea_2                      matlab.ui.control.TextArea
        ModelandFittingTab              matlab.ui.container.Tab
        UpdatePlotsButton               matlab.ui.control.Button
        AutoUpdatePlotsCheckBox         matlab.ui.control.CheckBox
        RandomizeParametersWithinBoundsButton  matlab.ui.control.Button
        model_parameters_tabs           matlab.ui.container.TabGroup
        ModelTab_2                      matlab.ui.container.Tab
        AddKuboButton                   matlab.ui.control.Button
        RemoveKuboButton                matlab.ui.control.Button
        ModelParamTable                 matlab.ui.control.Table
        LoadFromFileButton              matlab.ui.control.Button
        SaveToFileButton                matlab.ui.control.Button
        InitialGuessTab                 matlab.ui.container.Tab
        InitialGuessParamTable          matlab.ui.control.Table
        SaveToFileButton_2              matlab.ui.control.Button
        BestFitTab                      matlab.ui.container.Tab
        BestFitParamTable               matlab.ui.control.Table
        SaveToFileButton_5              matlab.ui.control.Button
        SimulationTab                   matlab.ui.container.Tab
        SimParamTable                   matlab.ui.control.Table
        CopyFromModelButton             matlab.ui.control.Button
        SimulateDataButton              matlab.ui.control.Button
        LoadFromFileButton_4            matlab.ui.control.Button
        SaveToFileButton_4              matlab.ui.control.Button
        AddKuboButton_2                 matlab.ui.control.Button
        RemoveKuboButton_2              matlab.ui.control.Button
        CompareSimulationOnlyTab        matlab.ui.container.Tab
        CompareTable                    matlab.ui.control.Table
        Label                           matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        ModelFittingPanel               matlab.ui.container.Panel
        StartFittingButton              matlab.ui.control.Button
        PauseFittingButton              matlab.ui.control.StateButton
        StopFittingButton               matlab.ui.control.StateButton
        UpdateInAppPlotsAtEachIterationCheckBox  matlab.ui.control.CheckBox
        CPU_GPU_switch                  matlab.ui.control.ToggleSwitch
        SimulationAxesTab               matlab.ui.container.Tab
        PumpAxisPanel                   matlab.ui.container.Panel
        StepSizepsSpinnerLabel          matlab.ui.control.Label
        StepSizepsSpinner               matlab.ui.control.Spinner
        RotatingFramecm1SpinnerLabel    matlab.ui.control.Label
        RotatingFramecm1Spinner         matlab.ui.control.Spinner
        NoPumpAxisPointsLabel           matlab.ui.control.Label
        NoPumpPointsSpinner             matlab.ui.control.Spinner
        Startcm1EditFieldLabel          matlab.ui.control.Label
        Startcm1EditField               matlab.ui.control.NumericEditField
        Endcm1EditFieldLabel            matlab.ui.control.Label
        Endcm1EditField                 matlab.ui.control.NumericEditField
        StepSizecm1EditFieldLabel       matlab.ui.control.Label
        StepSizecm1EditField            matlab.ui.control.NumericEditField
        ProbeAxisPanel                  matlab.ui.container.Panel
        Startcm1SpinnerLabel            matlab.ui.control.Label
        Startcm1Spinner                 matlab.ui.control.Spinner
        Endcm1SpinnerLabel              matlab.ui.control.Label
        Endcm1Spinner                   matlab.ui.control.Spinner
        NoProbePointsLabel              matlab.ui.control.Label
        NoProbePointsSpinner            matlab.ui.control.Spinner
        RotatingFramecm1EditFieldLabel  matlab.ui.control.Label
        RotatingFramecm1EditField       matlab.ui.control.NumericEditField
        StepSizecm1EditField_3Label     matlab.ui.control.Label
        StepSizecm1EditField_3          matlab.ui.control.NumericEditField
        StepSizepsEditFieldLabel        matlab.ui.control.Label
        StepSizepsEditField             matlab.ui.control.NumericEditField
        SimErrorBox                     matlab.ui.control.TextArea
        WaitingTimeAxisPanel            matlab.ui.container.Panel
        SimTwInvVarTable                matlab.ui.control.Table
        AddTwPointButton                matlab.ui.control.Button
        DeleteTwPointButton             matlab.ui.control.Button
        NoTwPointsEditFieldLabel        matlab.ui.control.Label
        NoSimTwPoints                   matlab.ui.control.NumericEditField
        LoadTwAxis                      matlab.ui.control.Button
        SaveTwAxis                      matlab.ui.control.Button
        OtherPanel                      matlab.ui.container.Panel
        NumericTypeDropDownLabel        matlab.ui.control.Label
        NumericTypeDropDown             matlab.ui.control.DropDown
        GaussianNoiseStdDevLabel        matlab.ui.control.Label
        GaussianNoiseStdDevSpinner      matlab.ui.control.Spinner
        CopyFromDataButton              matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        DTab                            matlab.ui.container.Tab
        Data2DPlot                      matlab.ui.control.UIAxes
        Model2DPlot                     matlab.ui.control.UIAxes
        Residual2DPlot                  matlab.ui.control.UIAxes
        Tw_label                        matlab.ui.control.Label
        Tw_dd                           matlab.ui.control.DropDown
        ProbeMinLabel                   matlab.ui.control.Label
        ProbeMin                        matlab.ui.control.Spinner
        ProbeMaxLabel                   matlab.ui.control.Label
        ProbeMax                        matlab.ui.control.Spinner
        PumpMinLabel                    matlab.ui.control.Label
        PumpMin                         matlab.ui.control.Spinner
        PumpMaxLabel                    matlab.ui.control.Label
        PumpMax                         matlab.ui.control.Spinner
        DTab_2                          matlab.ui.container.Tab
        FID_probe_axis_plot             matlab.ui.control.UIAxes
        FID_pump_axis_plot              matlab.ui.control.UIAxes
        TwLabel                         matlab.ui.control.Label
        FID_probe_axis_Tw_dd            matlab.ui.control.DropDown
        t1Label                         matlab.ui.control.Label
        FID_probe_axis_t1_dd            matlab.ui.control.DropDown
        FID_Tw_label                    matlab.ui.control.Label
        FID_pump_axis_Tw_dd             matlab.ui.control.DropDown
        FID_w3_label                    matlab.ui.control.Label
        FID_pump_axis_w3_dd             matlab.ui.control.DropDown
        VIFsTab                         matlab.ui.container.Tab
        VIF_plot                        matlab.ui.control.UIAxes
        ProgramUpdatingLampLabel        matlab.ui.control.Label
        ProgramUpdatingLamp             matlab.ui.control.Lamp
        FeedbackBox                     matlab.ui.control.TextArea
        ClearFeedbackButton             matlab.ui.control.Button
        FeedbackBoxLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        x;
		p;
		p_init_guess;
		p_best_fit;
		p_sim;
		p_predicted_fit;
		D_FID;
		D_spec;
		M_FID;
		sim_M_FID;
		M_spec;
		InvVar = struct('pump',[],'probe',[],'Tw',[]);
		mask = struct('pump',[],'probe',[],'Tw',[]);
		InvVar_masked = struct('pump',[],'probe',[],'Tw',[],'prod',[]);
        selected_param_rows;
        temp_x;
		temp_mask;
		temp_InvVar;
        selected_sim_Tw_rows;
		D_Tw_files;
		D_load_probe_axis;
		last_path;
        sim_noise;
        ax_arr;
        is_simulated;
        is_fitting = 0;
		complex_data_file_names;
    end
    
    methods (Access = public)      
        function update_2D_plots(app)
            update_2D_plot_data(app);
			update_2D_plot_model(app);
			update_2D_plot_residual(app);
        end
        
        function update_2D_plot_model(app)
			try
				ax = app.Model2DPlot;
				app.ProgramUpdatingLamp.Color = [0,1,0];app.ProgramUpdatingLampLabel.Visible = 1;drawnow;
				n_Tw = app.Tw_dd.Value;
				w1_lim = sort([app.PumpMin.Value,app.PumpMax.Value]);
				w3_lim = sort([app.ProbeMin.Value,app.ProbeMax.Value]);
				if ~isempty(app.M_spec)
					plot_2Dspec(ax,app.x,[w1_lim(1),w1_lim(2)],[w3_lim(1),w3_lim(2)],app.M_spec(:,:,n_Tw),'Model');
				else
					cla(ax);
				end
				app.ProgramUpdatingLamp.Color = [0.94,0.94,0.94];app.ProgramUpdatingLampLabel.Visible = 0;
				if ~isempty(app.InvVar.probe) && ~isempty(app.InvVar.pump)
					min_x = w1_lim(1);
					max_x = w1_lim(2);
					min_y = app.x.w3(app.mask.probe(1));
					max_y = app.x.w3(app.mask.probe(2));
					hold(ax,'on')
					patch_x = [min_x,max_x,max_x,min_x];
					patch_y = [max_y,max_y,min_y,min_y];
					patch(ax,'XData',patch_x,'YData',patch_y,'FaceColor',[0.5,0.5,0.5],'FaceAlpha',0.1);
					hold(ax,'off')
				end
			catch exception
				update_feedback_box(app,exception);
			end
		end
        
        function update_2D_plot_data(app)
			try
				ax = app.Data2DPlot;
				app.ProgramUpdatingLamp.Color = [0,1,0];app.ProgramUpdatingLampLabel.Visible = 1;drawnow;
				n_Tw = app.Tw_dd.Value;
				w1_lim = sort([app.PumpMin.Value,app.PumpMax.Value]);
				w3_lim = sort([app.ProbeMin.Value,app.ProbeMax.Value]);
				if ~isempty(app.D_spec)
					plot_2Dspec(ax,app.x,[w1_lim(1),w1_lim(2)],[w3_lim(1),w3_lim(2)],app.D_spec(:,:,n_Tw),'Data');
				else
					cla(ax);
				end
				if ~isempty(app.InvVar.probe) && ~isempty(app.InvVar.pump)
					min_x = w1_lim(1);
					max_x = w1_lim(2);
					min_y = app.x.w3(app.mask.probe(1));
					max_y = app.x.w3(app.mask.probe(2));
					hold(ax,'on')
					patch_x = [min_x,max_x,max_x,min_x];
					patch_y = [max_y,max_y,min_y,min_y];
					patch(ax,'XData',patch_x,'YData',patch_y,'FaceColor',[0.5,0.5,0.5],'FaceAlpha',0.1);
					hold(ax,'off')
				end
				app.ProgramUpdatingLamp.Color = [0.94,0.94,0.94];app.ProgramUpdatingLampLabel.Visible = 0;
			catch exception
				update_feedback_box(app,exception);
			end
        end
		
		function update_2D_plot_residual(app)
			try
				ax = app.Residual2DPlot;
				app.ProgramUpdatingLamp.Color = [0,1,0];app.ProgramUpdatingLampLabel.Visible = 1;drawnow;
				n_Tw = app.Tw_dd.Value;
				w1_lim = sort([app.PumpMin.Value,app.PumpMax.Value]);
				w3_lim = sort([app.ProbeMin.Value,app.ProbeMax.Value]);
				if ~isempty(app.D_spec) && ~isempty(app.M_spec)
					plot_2Dspec(ax,app.x,[w1_lim(1),w1_lim(2)],[w3_lim(1),w3_lim(2)],app.D_spec(:,:,n_Tw)-app.M_spec(:,:,n_Tw),'Residual (Data - Model)');
				else
					cla(ax);
				end
				if ~isempty(app.InvVar.probe) && ~isempty(app.InvVar.pump)
					min_x = w1_lim(1);
					max_x = w1_lim(2);
					min_y = app.x.w3(app.mask.probe(1));
					max_y = app.x.w3(app.mask.probe(2));
					hold(ax,'on')
					patch_x = [min_x,max_x,max_x,min_x];
					patch_y = [max_y,max_y,min_y,min_y];
					patch(ax,'XData',patch_x,'YData',patch_y,'FaceColor',[0.5,0.5,0.5],'FaceAlpha',0.1);
					hold(ax,'off')
				end
				app.ProgramUpdatingLamp.Color = [0.94,0.94,0.94];app.ProgramUpdatingLampLabel.Visible = 0;
			catch exception
				update_feedback_box(app,exception);
			end
			
        end
        
        function update_FID_probe_axis_plot(app)
			try
				ax = app.FID_probe_axis_plot;
				app.ProgramUpdatingLamp.Color = [0,1,0];app.ProgramUpdatingLampLabel.Visible = 1;drawnow;
				n_Tw = app.FID_probe_axis_Tw_dd.Value;
				n_t1 = app.FID_probe_axis_t1_dd.Value;
				if ~isempty(app.M_FID) && ~isempty(app.D_FID)
					plot(ax,app.x.w3,real(app.M_FID(n_t1,:,n_Tw)),'r-',app.x.w3,real(app.D_FID(n_t1,:,n_Tw)),'k-');
					legend(ax,'Model','Data');
				elseif ~isempty(app.M_FID) && isempty(app.D_FID)
					plot(ax,app.x.w3,real(app.M_FID(n_t1,:,n_Tw)),'r-');
					legend(ax,'Model');
				elseif isempty(app.M_FID) && ~isempty(app.D_FID)
					plot(ax,app.x.w3,real(app.D_FID(n_t1,:,n_Tw)),'k-');
					legend(ax,'Data');
				end
				if ~isempty(app.InvVar.probe)
					min_x = app.x.w3(app.mask.probe(1));
					max_x = app.x.w3(app.mask.probe(2));
					min_y = ax.YLim(1);
					max_y = ax.YLim(2);
					hold(ax,'on')
					patch_x = [min_x,max_x,max_x,min_x];
					patch_y = [max_y,max_y,min_y,min_y];
					patch(ax,'XData',patch_x,'YData',patch_y,'FaceColor',[0.5,0.5,0.5],'FaceAlpha',0.1);
					if isprop(ax.Legend,'String')
						ax.Legend.String{length(ax.Legend.String)} = "Mask";
					else
						legend(ax,"Mask");
					end
					hold(ax,'off')
				end
				app.ProgramUpdatingLamp.Color = [0.94,0.94,0.94];app.ProgramUpdatingLampLabel.Visible = 0;
			catch exception
				update_feedback_box(app,exception);
			end
        end
        
        function update_FID_pump_axis_plot(app)
			try
				ax = app.FID_pump_axis_plot;
				app.ProgramUpdatingLamp.Color = [0,1,0];app.ProgramUpdatingLampLabel.Visible = 1;drawnow;
				n_Tw = app.FID_pump_axis_Tw_dd.Value;
				n_w3 = app.FID_pump_axis_w3_dd.Value;
				if ~isempty(app.M_FID) && ~isempty(app.D_FID)
					plot(ax,app.x.t1,real(app.M_FID(:,n_w3,n_Tw)),'r-',app.x.t1,real(app.D_FID(:,n_w3,n_Tw)),'k-');
					legend(ax,'Model','Data');
				elseif ~isempty(app.M_FID) && isempty(app.D_FID)
					plot(ax,app.x.t1,real(app.M_FID(:,n_w3,n_Tw)),'r-');
					legend(ax,'Model');
				elseif isempty(app.M_FID) && ~isempty(app.D_FID)
					plot(ax,app.x.t1,real(app.D_FID(:,n_w3,n_Tw)),'k-');
					legend(ax,'Data');
				end
				if ~isempty(app.InvVar.pump)
					min_x = app.x.t1(app.mask.pump(1));
					max_x = app.x.t1(app.mask.pump(2));
					min_y = ax.YLim(1);
					max_y = ax.YLim(2);
					hold(ax,'on')
					patch_x = [min_x,max_x,max_x,min_x];
					patch_y = [max_y,max_y,min_y,min_y];
					patch(ax,'XData',patch_x,'YData',patch_y,'FaceColor',[0.5,0.5,0.5],'FaceAlpha',0.1);
					if isprop(ax.Legend,'String')
						ax.Legend.String{length(ax.Legend.String)} = "Mask";
					else
						legend(ax,"Mask");
					end
					hold(ax,'off')
				end
				app.ProgramUpdatingLamp.Color = [0.94,0.94,0.94];app.ProgramUpdatingLampLabel.Visible = 0;
			catch exception
				update_feedback_box(app,exception);
			end
		end
        
        function update_all_plots(app)
            update_2D_plot_data(app);
			update_2D_plot_model(app);
			update_2D_plot_residual(app);
			update_FID_probe_axis_plot(app);
			update_FID_pump_axis_plot(app);
			update_VIF_plot(app);
        end
        
        function update_mask_and_InvVar(app)
			try
				% doesn't change anything if app.x is empty
				if ~isempty(app.x)
					% pump axis
						limits = sort([app.Pump_mask_dd1.Value,app.Pump_mask_dd2.Value]);
						app.mask.pump = limits;
						if isempty(app.InvVar.pump)
							app.InvVar.pump = ones([app.x.N1,1,1]);
						end
						app.InvVar_masked.pump = zeros([app.x.N1,1,1]);
						app.InvVar_masked.pump(limits(1):limits(2),1,1) = app.InvVar.pump(limits(1):limits(2));
					% probe axis
						limits = sort([app.Probe_mask_dd1.Value,app.Probe_mask_dd2.Value]);
						app.mask.probe = limits;
						if isempty(app.InvVar.probe)
							app.InvVar.probe = ones([1,app.x.N3,1]);
						end
						app.InvVar_masked.probe = zeros([1,app.x.N3,1]);
						app.InvVar_masked.probe(1,limits(1):limits(2),1) = app.InvVar.probe(limits(1):limits(2));
						app.NumberofPointsEditField.Value = numel(limits(1):limits(2));
					% Tw axis
						app.mask.Tw = app.InvVarTwTable.Data(:,3);
						if isempty(app.InvVar.Tw)
							app.InvVar.Tw = ones([1,1,app.x.N2]);
						end
						app.InvVar_masked.Tw = zeros([1,1,app.x.N2]);
						app.InvVar_masked.Tw(1,1,find(app.mask.Tw)) = app.InvVar.Tw(1,1,find(app.mask.Tw));
					% 3D mask
						app.InvVar_masked.prod = (app.InvVar_masked.pump).*(app.InvVar_masked.probe).*(app.InvVar_masked.Tw);
				end
			catch exception
				update_feedback_box(app,exception);
			end
				
        end
		
        function init_InvVar_dd_and_table(app)
			try
				if ~isempty(app.x)
					% pump axis drop down
						app.Pump_mask_dd1.Items = string(app.x.t1)+" ps";
						app.Pump_mask_dd1.ItemsData = 1:app.x.N1;
						app.Pump_mask_dd1.Value = 1;
						app.Pump_mask_dd2.Items = string(app.x.t1)+" ps";
						app.Pump_mask_dd2.ItemsData = 1:app.x.N1;
						app.Pump_mask_dd2.Value = app.x.N1;
						if ~isempty(app.mask.pump)
							app.Pump_mask_dd1.Value = app.mask.pump(1);
							app.Pump_mask_dd2.Value = app.mask.pump(2);
						end
					% probe axis drop down
						app.Probe_mask_dd1.Items = string(app.x.w3)+" cm-1";
						app.Probe_mask_dd1.ItemsData = 1:app.x.N3;
						app.Probe_mask_dd1.Value = 1;
						app.Probe_mask_dd2.Items = string(app.x.w3)+" cm-1";
						app.Probe_mask_dd2.ItemsData = 1:app.x.N3;
						app.Probe_mask_dd2.Value = app.x.N3;
						if ~isempty(app.mask.probe)
							app.Probe_mask_dd1.Value = app.mask.probe(1);
							app.Probe_mask_dd2.Value = app.mask.probe(2);
						end
					% Tw table
						prev_size = size(app.InvVarTwTable.Data,1);
						if length(app.x.Tw(:)) ~= prev_size
							app.InvVarTwTable.Data = [];
							app.InvVarTwTable.ColumnFormat = {[],[],'logical'};
							app.InvVarTwTable.Data(:,1) = app.x.Tw(:);
							app.InvVarTwTable.Data(:,2) = 1;
							app.InvVarTwTable.Data(:,3) = 1;
							app.InvVarTwTable.RowName = 1:app.x.N2;
						end
						if ~isempty(app.InvVar.Tw)
							app.InvVarTwTable.Data(:,2) = app.InvVar.Tw;
						end
						if ~isempty(app.mask.Tw)
							app.InvVarTwTable.Data(:,3) = app.mask.Tw;
						end
				end
			catch exception
				update_feedback_box(app,exception);
			end
        end
        
        function initParamTable(app)
			initData = {'A01' 'variable' 1 1e-2 1e2 'arb. units' 'A_{01}' 0 0};
			set(app.ModelParamTable,'ColumnFormat',({[],{'constant','variable'},[],[],[],[],[],[],[]}),'Data',initData,'RowName',{'1'})
		end
        
		function t = ParamStruct2ParamTable(app,t,p)
			try
				fn = fieldnames(p);
				num_param = length(fn);
				t.Data = [];
				t.ColumnFormat = {[],{'constant','variable'},[],[],[],[],[],[],[]};
				for i=1:num_param
					switch p.(fn{i}).var
						case 0
							type = 'constant';
						case 1
							type = 'variable';
					end
					t.Data = [t.Data; {fn{i} type p.(fn{i}).val p.(fn{i}).bounds1 p.(fn{i}).bounds2 p.(fn{i}).units p.(fn{i}).label p.(fn{i}).SD p.(fn{i}).CI}]; 
				end
				t.RowName = {1:length(fn)};
				t = check_table(app,t,p);
			catch exception
				update_feedback_box(app,exception);
			end
		end
        
        function p = ParamTable2ParamStruct(app,t,p)
			try
				num_param = length(t.Data);
				p = []; % first clear the struct before writing to it
				for i=1:num_param
					switch t.Data{i,2}
						case 'constant'
							var = 0;
							SD = nan;
							CI = nan;
						case 'variable'
							var = 1;
							SD = t.Data{i,8};
							CI = t.Data{i,9};
					end
						p.(t.Data{i,1}) = struct('var',var,'val',t.Data{i,3},'bounds1',t.Data{i,4},'bounds2',t.Data{i,5},'units',t.Data{i,6},'label',t.Data{i,7},'SD',SD,'CI',CI);
				end
			catch exception
				update_feedback_box(app,exception);
			end
		end

        function model_fitting(app)
			% initialize error flag
				error_flag = 0;
			%% set plot limits
				w3_plot_lim = [app.x.w3(1),app.x.w3(app.x.N3)];
			%% set SIGN limit
				SIGN_lim = 1e-9;
			%% initialize figures
				update_fig = findobj('tag','update_fig');
				if isempty(update_fig)
					update_fig = figure;
					set(update_fig,'tag','update_fig','Name','Fitting Update Window','NumberTitle','off','WindowState','Maximized');
					set(update_fig,'Position',[20 50 1500 700]);
				end
				figure(update_fig);
			%% initialize aux struct
				p_guess = app.p_init_guess;
				aux = ILS_initialize_aux(app.p_init_guess);
			%% gather list of fieldnames
				fn = fieldnames(p_guess);
			%% gpu
				switch app.CPU_GPU_switch.Value
					case 'CPU'
						aux.gpuComputing = 0;
					case 'GPU'
						aux.gpuComputing = 1;
				end
				if aux.gpuComputing
					app.x.t1 = gpuArray(app.x.t1);
					app.x.Tw = gpuArray(app.x.Tw);
					app.x.t3 = gpuArray(app.x.t3);
					app.D_FID = gpuArray(app.D_FID);
					app.InvVar_masked.prod = gpuArray(app.InvVar_masked.prod);
					for i=1:length(fn)
						p_guess.(fn{i}).val = gpuArray(p_guess.(fn{i}).val);
					end
				end
			%% start timer
				timerval = tic;
			%% iterative fitting:
				for iter=1:200
					%% find p_min for this iteration
						if iter == 1
							p_prev = p_guess;
						else
							p_prev = p_arr(iter-1);
						end
						[p_arr(iter),~,C_arr(iter),SIGN_arr(iter),aux] = ILS_p_min(app.x,p_prev,app.D_FID,app.InvVar_masked.prod,aux);
					%% check for stall or convergence
						[aux,break_flag] = ILS_check_stall_conv(aux,C_arr,SIGN_arr,SIGN_lim,iter);
						if break_flag
							break
						end
					%% update fitting window
						plot_fit_update(update_fig,p_guess,p_arr,C_arr,SIGN_arr,SIGN_lim,iter,app.D_FID,app.x,app.InvVar_masked.prod,1,aux,timerval,w3_plot_lim);
					%% update in-app plots if requested by user
						if	app.UpdateInAppPlotsAtEachIterationCheckBox.Value
							update_M(app,p_arr(iter));
							update_all_plots(app);
						end
					%% check for pause in fitting
						while app.PauseFittingButton.Value == 1
							pause(1)
						end
					%% check for stop fitting
						if app.StopFittingButton.Value == 1
							app.StopFittingButton.Value = 0;
							error_flag = 1;
							break
						end
				end
				%% pick best fit (i.e. lowest cost function value)
					[~,I] = min(C_arr);
					app.p_best_fit = p_arr(I);
				%% upload GPU data back to CPU
					if aux.gpuComputing
						app.x.t1 = gather(app.x.t1);
						app.x.Tw = gather(app.x.Tw);
						app.x.t3 = gather(app.x.t3);
						app.D_FID = gather(app.D_FID);
						app.InvVar_masked.prod = gather(app.InvVar_masked.prod);
						for i=1:length(fn)
							app.p_best_fit.(fn{i}).val = gather(app.p_best_fit.(fn{i}).val);
						end
					end
				%% update app.p with app.p_best_fit, best fit table and model table
					app.p = app.p_best_fit;
					app.BestFitParamTable = ParamStruct2ParamTable(app,app.BestFitParamTable,app.p_best_fit);
					app.SaveToFileButton_5.Enable = 'on';
					app.ModelParamTable = ParamStruct2ParamTable(app,app.ModelParamTable,app.p);
				%% if data is simulated, update comparison table
					if app.is_simulated
						fn_sim = fieldnames(app.p_sim);
						if all(strcmp(fn_sim,fn_sim))
							for i=1:length(fn)
								if app.p_best_fit.(fn{i}).var
									app.CompareTable.Data(i,4:5) = {app.p_best_fit.(fn{i}).val,app.p_best_fit.(fn{i}).CI};
								else
									app.CompareTable.Data(i,4:5) = {[],[]};
								end
							end
						end
					end
			%% clear p_fit, SIGN, C and aux before next trial
				clear p_fit SIGN C aux
			%% update model and all plots
				update_M(app);
				update_all_plots(app);
			%% update best fit table, bring GUI back to front, and change app.is_fitting flag
				app.model_parameters_tabs.SelectedTab = app.BestFitTab;
				figure(app.UIFigure);
				app.is_fitting = 0;
			%% provide feedback to user
				if break_flag == 1
					app.FeedbackBox.Value = 'Fitting complete. Convergence successful! :)';
				elseif error_flag
					app.FeedbackBox.Value = 'Fitting stopped by user. Convergence unsuccessful. :(';
				else
					app.FeedbackBox.Value = 'Fitting stopped by maximum iteration limit. Convergence unsuccessful. :(';
				end
		end
		
        function check_sim_x(app)
			try
				error = 'The following error(s) occurred:';
				% check No. pump pnts > 1 (pump axis)
				if app.NoPumpPointsSpinner.Value < 1
					error = [error,newline,'No. Pump Points < 1'];
				end
				if app.NoPumpPointsSpinner.Value == 1
					app.StepSizepsSpinner.Value = 0;
				end
				% check step size > 0
				if app.StepSizepsSpinner.Value <= 0
					error = [error,newline,'Pump Step Size <= 0'];
				end
				% check probe start >= 0
				if app.Startcm1Spinner.Value < 0
					error = [error,newline,'Probe Start <=0'];
				end
				% check probe start < end
				if app.Endcm1Spinner.Value <= app.Startcm1Spinner.Value
					error = [error,newline,'Probe End <= Start'];
				end
				% check No. Tw pnts > 0
				app.NoSimTwPoints.Value = size(app.SimTwInvVarTable.Data,1);
				if app.NoSimTwPoints.Value < 1
					error = [error,newline,'No. Tw Points < 1'];
				end
				% 
				if strcmp(error,'The following error(s) occurred:')
					app.SimErrorBox.Value = '';
					app.SimulateDataButton.Enable = 'on';
					app.temp_x = gen_x([0,(app.NoPumpPointsSpinner.Value-1)*app.StepSizepsSpinner.Value],app.NoPumpPointsSpinner.Value,app.RotatingFramecm1Spinner.Value,[app.Startcm1Spinner.Value,app.Endcm1Spinner.Value],app.NoProbePointsSpinner.Value,app.SimTwInvVarTable.Data(:,1),app.NumericTypeDropDown.Value);
					app.Startcm1EditField.Value = app.temp_x.w1(1);
					app.Endcm1EditField.Value = app.temp_x.w1(app.temp_x.N1);
					if app.temp_x.N1 == 1
						app.StepSizecm1EditField.Value = 0;
					else
						app.StepSizecm1EditField.Value = app.temp_x.w1(2) - app.temp_x.w1(1);
					end
					app.RotatingFramecm1EditField.Value = app.temp_x.w3(1);
					app.RotatingFramecm1EditField.Value = app.temp_x.w3(1);
					app.StepSizecm1EditField_3.Value = app.temp_x.w3(2) - app.temp_x.w3(1);
					app.StepSizepsEditField.Value = app.temp_x.t3(2) - app.temp_x.t3(1);
					app.NoSimTwPoints.Value = app.temp_x.N2;
				else
					app.SimErrorBox.Value = error;
					app.SimulateDataButton.Enable = 'off';
				end
			catch exception
				update_feedback_box(app,exception);
			end
		end
        
		function check_data_loading(app)
			try
				feedback = string([]); % initialize feedback as empty
				% first check to see if Tw files are ok to load
					% check that app.D_Tw_files.Tw contains no NaNs or INFs
						if ~isempty(app.D_Tw_files)
							if isfield(app.D_Tw_files,'Tw')
								if any(isnan([app.D_Tw_files.Tw])) || any(isinf([app.D_Tw_files.Tw]))
									feedback = [feedback;'ERROR: One or more Tw is NaN or Inf. Try changing the ''Tw Column'' drop-down to align with Tw values.'];
								end
							else
								feedback = [feedback;'ERROR: Tw is not a field of app.D_Tw_files.'];
							end
						else
							feedback = [feedback;'ERROR: No file names loaded. Go to File -> Load -> Data -> Axes Separate -> Gather Tw File Names.'];
						end
					% enable load Tw files button or print error(s)
						if isempty(feedback)
							app.LoadTwFilesButton.Enable = 'on';
							app.FeedbackBox.Value = '';
						else
							app.LoadTwFilesButton.Enable = 'off';
							app.FeedbackBox.Value = feedback;
						end
						
				% now check if loading is ready for completion
					% make sure data is the same size for every Tw file
						if ~isempty(app.D_Tw_files)
							if isfield(app.D_Tw_files,'data')
								if app.VerifiedCheckBox.Value
									if ~isempty(app.D_load_probe_axis)
										if any(isnan(app.D_load_probe_axis)) || any(isinf(app.D_load_probe_axis))
											feedback = [feedback;'ERROR: Probe axis contains NaNs or Infs.'];
										else
											poly2_fit = fit((1:numel(app.D_load_probe_axis))',reshape(app.D_load_probe_axis,[numel(app.D_load_probe_axis),1]),'poly2');
											if abs(poly2_fit.p1/(poly2_fit.p2)^2) > 1e-12
												linearize_probe_axis(app);
											end
											switch app.OrientationDropDown.Value
												case app.OrientationDropDown.Items{1}
													if size(app.D_Tw_files(1).data,2) ~= length(app.D_load_probe_axis)
														feedback = [feedback;'ERROR: Probe axis and Tw spectra do not match in size.'];
													end
												case app.OrientationDropDown.Items{2}
													if size(app.D_Tw_files(1).data,1) ~= length(app.D_load_probe_axis)
														feedback = [feedback;'ERROR: Probe axis and Tw spectra do not match in size.'];
													end
											end
											for i=1:length(app.D_Tw_files)
												if size(app.D_Tw_files(1).data,1) ~= size(app.D_Tw_files(i).data,1)
													feedback = [feedback;'ERROR: Tw spectra are not uniform in size.'];
												elseif size(app.D_Tw_files(1).data,2) ~= size(app.D_Tw_files(i).data,2)
													feedback = [feedback;'ERROR: Tw spectra are not uniform in size.'];
												end
											end
										end
									else
										feedback = [feedback;'Probe axis not loaded. Try one of the following:';'(1) Load by menu: Load -> Data -> Axes Separate -> Load Probe Axis.';'(2) Generate the probe axis manually via the ''Probe Axis'' box.'];
									end
								else
									feedback = [feedback;'Go to: ''Pump Axis Box'', update the ''Rotating Frame (cm-1)'' and ''Time Step (ps)'', then check the ''Verified'' check box.'];
								end
							else
								feedback = [feedback;'Tw files not loaded. Try pressing the ''Load Tw Files'' button.'];
							end
						else
							feedback = [feedback;'ERROR: No file names loaded. Go to File -> Load -> Data -> Axes Separate -> Gather Tw File Names.'];
						end
					% enable complete loading button or print error(s)
						if isempty(feedback)
							app.CompleteLoadingButton.Enable = 'on';
							app.FeedbackBox.Value = '';
						else
							app.CompleteLoadingButton.Enable = 'off';
							app.FeedbackBox.Value = feedback;
						end
			catch exception
				update_feedback_box(app,exception);
			end
        end
		
        function update_plot_dd(app)
			try
				% update Tw drop down for 2D plots
					app.Tw_dd.Items = string(app.x.Tw(:))+" ps";
					app.Tw_dd.ItemsData = 1:app.x.N2;
					app.Tw_dd.Value = 1;
				% update Tw drop down for FID probe-slice plot
					app.FID_probe_axis_Tw_dd.Items = string(app.x.Tw(:))+" ps";
					app.FID_probe_axis_Tw_dd.ItemsData = 1:app.x.N2;
					app.FID_probe_axis_Tw_dd.Value = 1;
				% update t1 drop down for FID probe-slice plot
					app.FID_probe_axis_t1_dd.Items = string(app.x.t1)+" ps";
					app.FID_probe_axis_t1_dd.ItemsData = 1:app.x.N1;
					app.FID_probe_axis_t1_dd.Value = 1;
				% update Tw drop down for FID pump-slice plot
					app.FID_pump_axis_Tw_dd.Items = string(app.x.Tw(:))+" ps";
					app.FID_pump_axis_Tw_dd.ItemsData = 1:app.x.N2;
					app.FID_pump_axis_Tw_dd.Value = 1;
				% update w3 drop down for FID pump-slice plot
					app.FID_pump_axis_w3_dd.Items = string(app.x.w3)+" cm-1";
					app.FID_pump_axis_w3_dd.ItemsData = 1:app.x.N3;
					app.FID_pump_axis_w3_dd.Value = 1;
				% update slide limits and values for 2D plots
					app.PumpMin.Limits = [app.x.w1(1),app.x.w1(app.x.N1)];
					app.PumpMax.Limits = [app.x.w1(1),app.x.w1(app.x.N1)];
					app.ProbeMin.Limits = [app.x.w3(1),app.x.w3(app.x.N3)];
					app.ProbeMax.Limits = [app.x.w3(1),app.x.w3(app.x.N3)];
					app.PumpMin.Value = app.x.w1(1);
					app.PumpMax.Value = app.x.w1(app.x.N1);
					app.ProbeMin.Value = app.x.w3(1);
					app.ProbeMax.Value = app.x.w3(app.x.N3);
				% set default values for certain drop downs
					if ~isempty(app.D_FID)
						% set default probe frequency for FID slices
							[B,I] = max(abs(app.D_FID(1,:,1)));
							app.FID_pump_axis_w3_dd.Value = I;
					elseif ~isempty(app.M_FID)
						% set default probe frequency for FID slices
							[B,I] = max(abs(app.M_FID(1,:,1)));
							app.FID_pump_axis_w3_dd.Value = I;
					end
			catch exception
				update_feedback_box(app,exception);
			end
        end
        
        function update_D(app,varargin)
			try
				app.ProgramUpdatingLamp.Color = [0,1,0];app.ProgramUpdatingLampLabel.Visible = 1;drawnow;
				if nargin == 1
					app.D_spec = FID_to_2Dspec(app.D_FID);
				elseif nargin == 2
					app.D_FID = varargin{1};
					app.D_spec = FID_to_2Dspec(app.D_FID);
				end
				app.ProgramUpdatingLamp.Color = [0.96,0.96,0.96];app.ProgramUpdatingLampLabel.Visible = 0;
			catch exception
				update_feedback_box(app,exception);
			end
        end
        
        function update_M(app,varargin)
			try
				app.ProgramUpdatingLamp.Color = [0,1,0];app.ProgramUpdatingLampLabel.Visible = 1;drawnow;
				if ~isempty(app.x) && ~isempty(app.p)
					if nargin == 2
						app.p = varargin{1};
					end
					app.M_FID = ILS_M(app.x,app.p);
					app.M_spec = FID_to_2Dspec(app.M_FID);
				end
				app.ProgramUpdatingLamp.Color = [0.96,0.96,0.96];app.ProgramUpdatingLampLabel.Visible = 0;
			catch exception
				update_feedback_box(app,exception);
			end
        end
		
		function update_sim_M(app)
			try
				app.ProgramUpdatingLamp.Color = [0,1,0];app.ProgramUpdatingLampLabel.Visible = 1;drawnow;
				if ~isempty(app.x) && ~isempty(app.p_sim)
					app.sim_M_FID = ILS_M(app.x,app.p_sim);
				end
				app.ProgramUpdatingLamp.Color = [0.96,0.96,0.96];app.ProgramUpdatingLampLabel.Visible = 0;
			catch exception
				update_feedback_box(app,exception);
			end
        end
        
        function gather_Tw_file_list(app,path,files)
			try
				if all( contains(files,{'real','imag'},"IgnoreCase",true) )
					real_files_I = find(contains(files,'real'));
					imag_files_I = find(contains(files,'imag'));
					[real_files,real_files_I2] = sort(erase([{files{real_files_I}}],'real'));
					[imag_files,imag_files_I2] = sort(erase([{files{imag_files_I}}],'imag'));
					if length(real_files) == length(imag_files)
						if all(strcmp(real_files,imag_files))
							app.complex_data_file_names = 1;
						end
					else
						app.complex_data_file_names = 0;
					end
				else
					app.complex_data_file_names = 0;
				end
				
				temp = regexp(files{1},'([a-zA-Z._]+)|(\d+,)*\d+(\.\d*)?','match');
				num_cols = size(temp,2);
				app.D_Tw_files = [];
				app.Tw_files_table.Data = [];
				table_format = [];
				for i=1:num_cols
					table_format = [table_format,{'char'}];
				end
				app.TwColumnDropDown.Items = app.Tw_files_table.ColumnName;
				app.TwColumnDropDown.ItemsData = 1:num_cols;
				set(app.Tw_files_table,'ColumnFormat',table_format,'Data',temp)
				for i=1:size(files,2)
					app.D_Tw_files(i).path = path;
					app.D_Tw_files(i).filename = files{i};
					next_row = regexp(files{i},'([a-zA-Z._]+)|(\d+,)*\d+(\.\d*)?','match');
					if size(next_row,2) ~= num_cols
						app.FeedbackBox.Value = 'Error: Inconsistent file names.';
						return;
					end
					temp(i,:) = next_row;
				end
				app.Tw_files_table.Data = temp;
				app.Tw_files_table.RowName = 1:length(app.D_Tw_files);
				load_D_Tw_assignment(app);
				app.TabGroup.SelectedTab = app.DataTab;
			catch exception
				update_feedback_box(app,exception);
			end
        end
        
        function load_D_Tw_assignment(app)
			try
				if ~isempty(app.D_Tw_files)
					col = app.TwColumnDropDown.Value;
					switch app.TwUnitsDropDown.Value
						case 'fs'
							fctr = 1/1000;
						case 'ps'
							fctr = 1;
						case 'ns'
							fctr = 1000;
					end
					for i=1:length(app.D_Tw_files)
						app.D_Tw_files(i).Tw = double(string(app.Tw_files_table.Data(i,col)))*fctr;
					end
				end
			catch exception
				update_feedback_box(app,exception);
			end
        end
        
        function empty_M(app)
            app.M_FID = [];
			app.M_spec = [];
        end
		function empty_D(app)
            app.D_FID = [];
			app.D_spec = [];
        end
        
        function linearize_probe_axis(app)
			try
				update_feedback_box(app,'Frequency steps along probe axis are nonlinearly spaced. Interpolating Probe Axis Now...');
				lin_probe_axis = linspace(app.D_load_probe_axis(1),app.D_load_probe_axis(numel(app.D_load_probe_axis)),numel(app.D_load_probe_axis));
				for i=1:length(app.D_Tw_files)
					app.D_Tw_files(i).data = spline(app.D_load_probe_axis,app.D_Tw_files(i).data,lin_probe_axis);
				end
				app.D_load_probe_axis = lin_probe_axis;
			catch exception
				update_feedback_box(app,exception);
			end
        end
        
        function load_Tw_files(app)
			try
				app.FeedbackBox.Value = 'Loading Tw files...';
				app.LoadingLamp.Color = [0,1,0];app.LoadingLampLabel.Visible = 1;drawnow;
				for i=1:length(app.D_Tw_files)
					app.D_Tw_files(i).data = load([app.D_Tw_files(i).path,app.D_Tw_files(i).filename]);
				end
				check_data_loading(app);
				app.LoadingLamp.Color = [0.94,0.94,0.94];app.LoadingLampLabel.Visible = 0;
				app.FeedbackBox.Value = '';
			catch exception
				update_feedback_box(app,exception);
			end
        end
        
        function empty_mask_and_InvVar(app)
			app.mask.pump = [];
			app.mask.probe = [];
			app.mask.Tw = [];
			app.InvVar.pump = [];
			app.InvVar.probe = [];
			app.InvVar.Tw = [];
			app.InvVar_masked.pump = [];
			app.InvVar_masked.probe = [];
			app.InvVar_masked.Tw = [];
			app.InvVar_masked.prod = [];
			app.InvVarTwTable.Data = [];
        end
        
        function tbl = check_table(app,tbl,p)
			try
				if ~isempty(p)
					removeStyle(tbl);
					fn = fieldnames(p);
					error_cells = [];
					const_cells = [];
					for i=1:length(fn)
						if p.(fn{i}).var
							% check if field name is valid
							if ~isvarname(char(tbl.Data{i,1}))
								error_cells =[error_cells;i,1];
							end
							% check if value is valid
							if isnan(p.(fn{i}).val) || isinf(p.(fn{i}).val)
								error_cells =[error_cells;i,3];
							end
							% check if value is higher than lower bound
							if p.(fn{i}).bounds1 > p.(fn{i}).val
								error_cells =[error_cells;i,4];
							end
							% check if value is lower than higher bound
							if p.(fn{i}).bounds2 < p.(fn{i}).val
								error_cells =[error_cells;i,5];
							end
						else
							const_cells =[const_cells;[i*ones(9,1),(1:9)']];
						end
					end
					if ~isempty(error_cells)
						addStyle(tbl,uistyle('BackgroundColor',[1,0.5,0.5]),'cell',error_cells);
					end
					if ~isempty(const_cells)
						addStyle(tbl,uistyle('BackgroundColor',[1,0.9,0.6]),'cell',const_cells);
					end
				end
			catch exception
				update_feedback_box(app,exception);
			end
        end
        
        function update_VIF_plot(app)
			try
				ax = app.VIF_plot;
				if ~isempty(app.x) && ~isempty(app.p) && ~isempty(app.InvVar_masked.prod)
					cla(ax);
					cond_num = plot_VIF(ax,app.x,app.p,app.InvVar_masked.prod);
					ylim(ax,[1,1e10]);
					title(ax,sprintf('Multicollinearity\n(Condition Number = %.3e)',cond_num));
				end
			catch exception
				update_feedback_box(app,exception);
			end
        end
		function load_parameters(app,selected_tab)
			try
				[file,path] = uigetfile('*.csv','Select a File',[app.last_path]);
				figure(app.UIFigure);
				if file ~= 0
					app.last_path = path;
					try
						tbl = readtable([path,file],'ReadRowNames',true,'PreserveVariableNames',true);
					catch
						return
					end
					app.FeedbackBox.Value = '';
					if isempty('tbl')
						app.FeedbackBox.Value = 'ERROR: Loading parameters failed. Table is empty.';
					elseif size(tbl,2) ~= 9
						app.FeedbackBox.Value = 'ERROR: Loading parameters failed. Table must have 10 columns.';
					elseif ~all(strcmp(tbl.Properties.VariableNames,{'Field Name','Type','Value','Lower Bound','Upper Bound','Units','Plot Label','Std. Dev.','95% C.I.'}))
						app.FeedbackBox.Value = 'ERROR: Loading parameters failed. First 10 columns of .csv file must be: Row | Field Name | Type | Value | Lower Bound | Upper Bound | Units | Plot Label | Std. Dev. | 95% C.I.';
					elseif size(tbl,1) < 1
						app.FeedbackBox.Value = 'ERROR: Loading parameters failed. Table has no parameters.';
					else
						for i=1:size(tbl,1)
							if ~isvarname(char(tbl{i,1}))
								app.FeedbackBox.Value = sprintf('ERROR: Parameter ''%s'' (Row %i) was skipped while loading due to invalid Field Name.',string(tbl{i,1}),i);
								continue
							end
							switch char(tbl{i,2})
								case 'variable'
									var = 1;
								case 'constant'
									var = 0;
								otherwise
									app.FeedbackBox.Value = sprintf('ERROR: Parameter ''%s'' (Row %i) is neither ''variable'' nor ''constant''. Defaulting to constant.',string(tbl{i,1}),i);
									var = 0;
							end
							if isnan(tbl{i,3})
								app.FeedbackBox.Value = sprintf('ERROR: Parameter ''%s'' (Row %i) was skipped while loading due to NaN value.',string(tbl{i,1}),i);
								continue
							end
							if isinf(tbl{i,3})
								app.FeedbackBox.Value = sprintf('ERROR: Parameter ''%s'' (Row %i) was skipped while loading due to inf value.',string(tbl{i,1}),i);
								continue
							end
							if isnan(tbl{i,4}) || isnan(tbl{i,5})
								app.FeedbackBox.Value = sprintf('ERROR: Parameter ''%s'' (Row %i) was skipped while loading due to NaN bound(s).',string(tbl{i,1}),i);
								continue
							end
							if isinf(tbl{i,4}) || isinf(tbl{i,5})
								app.FeedbackBox.Value = sprintf('ERROR: Parameter ''%s'' (Row %i) was skipped while loading due to inf bound(s).',string(tbl{i,1}),i);
								continue
							end
							p.(char(tbl{i,1})) = struct('var',var,'val',tbl{i,3},'bounds1',tbl{i,4},'bounds2',tbl{i,5},'units',tbl{i,6},'label',tbl{i,7},'SD',tbl{i,8},'CI',tbl{i,9});
						end
						if ~exist('p','var')
							app.FeedbackBox.Value = sprintf('ERROR: No valid parameter found while loading.');
							return;
						end
						switch selected_tab
							case app.SimulationTab
								app.p_sim = [];
								app.p_sim = p;
								app.SimParamTable = ParamStruct2ParamTable(app,app.SimParamTable,app.p_sim);
								app.SimParamTable = check_table(app,app.SimParamTable,app.p_sim);
								check_sim_x(app);
							otherwise
								app.p = [];
								app.p = p;
								app.ModelParamTable = ParamStruct2ParamTable(app,app.ModelParamTable,app.p);
								if ~isempty(app.x)
									update_M(app);
									update_all_plots(app);
								end
								app.ModelParamTable = check_table(app,app.ModelParamTable,app.p);
								app.TabGroup.SelectedTab = app.ModelandFittingTab;
						end
					end
				end
			catch exception
				update_feedback_box(app,exception);
			end
		end
		
		function save_parameters(app,selectedTab)
			try
				tbl = findall(selectedTab.Children,'Type','uitable');
				new_tbl = cell2table(tbl.Data);
				new_tbl.Properties.VariableNames = tbl.ColumnName;
				new_tbl.Properties.RowNames = tbl.RowName;
				switch selectedTab
					case app.ModelTab_2
						[file,path] = uiputfile('*.csv','Save As',[app.last_path,'p model.csv']);
					case app.InitialGuessTab
						[file,path] = uiputfile('*.csv','Save As',[app.last_path,'p initial guess.csv']);
					case app.BestFitTab
						[file,path] = uiputfile('*.csv','Save As',[app.last_path,'p best fit.csv']);
					case app.SimulationTab
						[file,path] = uiputfile('*.csv','Save As',[app.last_path,'p simulation.csv']);
				end
				figure(app.UIFigure)
				if file ~= 0
					writetable(new_tbl,[path,file],'WriteRowNames',true,'WriteVariableNames',true);
				end
			catch exception
				update_feedback_box(app,exception);
			end
        end
		
		function update_feedback_box(app,feedback)
			if isprop(app,'FeedbackBox')
				switch class(feedback)
					case 'MException'
						if strcmp(app.FeedbackBox.Value,'')
							app.FeedbackBox.Value = ['MATLAB ERROR: ',feedback.message];
						else
							app.FeedbackBox.Value = [string(app.FeedbackBox.Value);['MATLAB ERROR: ',feedback.message]];
						end
					case 'string'
						if strcmp(app.FeedbackBox.Value,'')
							app.FeedbackBox.Value = feedback;
						else
							app.FeedbackBox.Value = [string(app.FeedbackBox.Value);feedback];
						end
					case 'char'
						if strcmp(app.FeedbackBox.Value,'')
							app.FeedbackBox.Value = feedback;
						else
							app.FeedbackBox.Value = [string(app.FeedbackBox.Value);feedback];
						end
					otherwise
						if strcmp(app.FeedbackBox.Value,'')
							app.FeedbackBox.Value = 'Invalid data type for ''feedback''';
						else
							app.FeedbackBox.Value = [string(app.FeedbackBox.Value);'Invalid data type for ''feedback'''];
						end
				end
			end
		end
		function tbl = add_kubo(app,tbl)
			try
				kubo_comp = [];
				for i=1:size(tbl.Data,1)
					kubo_comp = [kubo_comp,sscanf(tbl.Data{i,1},'kubo%i')];
				end
				if isempty(kubo_comp)
					kubo_comp = 0;
				elseif max(kubo_comp) >= 3
					return;
				end
				new_kubo_comp = max(kubo_comp)+1;
				nr1 = {['kubo',char(num2str(new_kubo_comp)),'_t'],'constant',1,0.1,10,'ps',['\tau_',char(num2str(new_kubo_comp))],nan,nan};
				nr2 = {['kubo',char(num2str(new_kubo_comp)),'_D2'],'constant',1,0.1,10,'cm^{-2}',['\Delta_',char(num2str(new_kubo_comp)),'^2'],nan,nan};
				tbl.Data = [tbl.Data;nr1;nr2];
				tbl.RowName = {1:length(tbl.Data)};
				app.selected_param_rows = [];
			catch exception
				update_feedback_box(app,exception);
			end
		end
		function tbl = remove_kubo(app,tbl)
			try
				if ~isempty(app.selected_param_rows)
					fn = tbl.Data{app.selected_param_rows,1};
					kubo_comp = sscanf(fn,'kubo%i');
					if ~isempty(kubo_comp)
						tbl.Data(app.selected_param_rows,:) = [];
						for i=1:size(tbl.Data,1)
							if strcmp(tbl.Data(i,1),['kubo',char(num2str(kubo_comp)),'_t'])
								tbl.Data(i,:) = [];
								break;
							elseif strcmp(tbl.Data{i,1},['kubo',char(num2str(kubo_comp)),'_D2'])
								tbl.Data(i,:) = [];
								break;
							end
						end
						kubo_comp = [];
						for i=1:size(tbl.Data,1)
							kubo_comp = [kubo_comp,sscanf(tbl.Data{i,1},'kubo%i')];
						end
						if ~isempty(kubo_comp)
							unique_kubo_comp = unique(kubo_comp);
							for k=1:length(unique_kubo_comp)
								for i=1:size(tbl.Data,1)
									if strcmp(tbl.Data(i,1),['kubo',char(num2str(unique_kubo_comp(k))),'_t'])
										tbl.Data{i,1} = ['kubo',char(num2str(k)),'_t'];
									elseif strcmp(tbl.Data{i,1},['kubo',char(num2str(unique_kubo_comp(k))),'_D2'])
										tbl.Data{i,1} = ['kubo',char(num2str(k)),'_D2'];
									end
								end
							end
						end
						app.selected_param_rows = [];
					end
				end
			catch exception
				update_feedback_box(app,exception);
			end
		end
    end

    
    methods (Access = private)
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
			try
				% maximize window
					drawnow;
					app.UIFigure.WindowState = 'maximized';
				% update selected tab
					app.TabGroup.SelectedTab = app.ModelandFittingTab;
					app.model_parameters_tabs.SelectedTab = app.InitialGuessTab;
					drawnow;
					app.model_parameters_tabs.SelectedTab = app.ModelTab_2;
					drawnow;
					app.TabGroup.SelectedTab = app.DataTab;
					drawnow;
				% add paths
					addpath('ILS Functions\');
					addpath('Lineshape Functions\');
					addpath('Miscellaneous Functions\');
					app.last_path = pwd;
				% update plots
					update_all_plots(app);
				% update simulation tab
					check_sim_x(app);
					app.complex_data_file_names = 0;
					app.LoadingLampLabel.Visible = 0;
					app.NumericTypeDropDown.ItemsData = {'real','complex'};
					app.is_simulated = false;
				% check for GPU computing
					if gpuDeviceCount > 0
						app.CPU_GPU_switch.Enable = 1;
					else
						app.CPU_GPU_switch.Enable = 0;
					end
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
		 % remove paths
			rmpath('ILS Functions\');
			rmpath('Lineshape Functions\');
			rmpath('Miscellaneous Functions\');
			update_fig = findobj('tag','update_fig');
			if ~isempty(update_fig)
				close(update_fig);
			end
			delete(app);
        end

        % Value changed function: Tw_dd
        function Tw_ddValueChanged(app, event)
			try
				app.ProgramUpdatingLamp.Color = [0.00,1.00,0.00];app.ProgramUpdatingLampLabel.Visible = 1;
				update_2D_plots(app)
				app.ProgramUpdatingLamp.Color = [0.94,0.94,0.94];app.ProgramUpdatingLampLabel.Visible = 0;
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Value changed function: FID_probe_axis_Tw_dd
        function FID_probe_axis_Tw_ddValueChanged(app, event)
            update_FID_probe_axis_plot(app);
        end

        % Value changed function: FID_probe_axis_t1_dd
        function FID_probe_axis_t1_ddValueChanged(app, event)
            update_FID_probe_axis_plot(app);
        end

        % Value changed function: FID_pump_axis_Tw_dd
        function FID_pump_axis_Tw_ddValueChanged(app, event)
            update_FID_pump_axis_plot(app);
        end

        % Value changed function: FID_pump_axis_w3_dd
        function FID_pump_axis_w3_ddValueChanged(app, event)
            update_FID_pump_axis_plot(app);
        end

        % Value changed function: Pump_mask_dd1
        function Pump_mask_dd1ValueChanged(app, event)
            update_mask_and_InvVar(app);
			update_all_plots(app);
        end

        % Value changed function: Pump_mask_dd2
        function Pump_mask_dd2ValueChanged(app, event)
            update_mask_and_InvVar(app);
			update_all_plots(app);
        end

        % Value changed function: Probe_mask_dd1
        function Probe_mask_dd1ValueChanged(app, event)
            update_mask_and_InvVar(app);
			update_all_plots(app);
        end

        % Value changed function: Probe_mask_dd2
        function Probe_mask_dd2ValueChanged(app, event)
            update_mask_and_InvVar(app);
			update_all_plots(app);
        end

        % Button pushed function: AddKuboButton
        function AddKuboButtonPushed(app, event)
			try
				app.ModelParamTable = add_kubo(app,app.ModelParamTable);
				app.p = ParamTable2ParamStruct(app,app.ModelParamTable,app.p);
				app.ModelParamTable = check_table(app,app.ModelParamTable,app.p);
				if app.AutoUpdatePlotsCheckBox.Value
					update_M(app);
					update_all_plots(app);
				end
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Cell edit callback: ModelParamTable
        function ModelParamTableCellEdit(app, event)
			try
				app.ModelParamTable.RowName = {1:length(app.ModelParamTable.Data)};
				app.p = ParamTable2ParamStruct(app,app.ModelParamTable,app.p);
				app.ModelParamTable = check_table(app,app.ModelParamTable,app.p);
				if app.AutoUpdatePlotsCheckBox.Value
					update_M(app)
					update_all_plots(app);
				end
				if ~isempty(app.ModelParamTable.Data)
					app.SaveToFileButton.Enable = true;
				end
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Button pushed function: RemoveKuboButton
        function RemoveKuboButtonPushed(app, event)
			try
				app.ModelParamTable = remove_kubo(app,app.ModelParamTable);
				app.ModelParamTable.RowName = {1:length(app.ModelParamTable.Data)};
				app.p = ParamTable2ParamStruct(app,app.ModelParamTable,app.p);
				app.ModelParamTable = check_table(app,app.ModelParamTable,app.p);
				if app.AutoUpdatePlotsCheckBox.Value
					update_M(app);
					update_all_plots(app);
				end
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Callback function
        function SaveParametersButtonPushed(app, event)
			try
				file = app.SaveParamsFilename.Value;
				app.p = ParamTable2ParamStruct(app,app.ModelParamTable,app.p);
				update_M(app)
				update_all_plots(app);
				p = app.p;
				save(file,'p');
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Cell selection callback: ModelParamTable
        function ModelParamTableCellSelection(app, event)
            temp = event.Indices;
			app.selected_param_rows = temp(:,1);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
			try
				selectedTab = app.TabGroup.SelectedTab;
				switch selectedTab
					case app.MaskandInverseVarianceTab
						update_mask_and_InvVar(app);
					case app.ModelandFittingTab
						if isempty(app.p_sim)
							app.SimulateDataButton.Enable = false;
						elseif ~isempty(app.p_sim)
							if ~isempty(app.x)
								app.SimulateDataButton.Enable = true;
							end
							check_sim_x(app);
						end
				end
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Button pushed function: StartFittingButton
        function push_start_fitting_button(app, event)
			try
				app.FeedbackBox.Value = 'Model fitting in progress...';
				app.is_fitting = 1;
				app.p = ParamTable2ParamStruct(app,app.ModelParamTable,app.p);
				app.p_init_guess = [];
				app.p_init_guess = app.p;
				app.InitialGuessParamTable = ParamStruct2ParamTable(app,app.InitialGuessParamTable,app.p_init_guess);
				check_table(app,app.InitialGuessParamTable,app.p_init_guess);
				update_mask_and_InvVar(app);
				model_fitting(app);
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Button pushed function: SimulateDataButton
        function push_simulate_button(app, event)
			try
				if ~isempty(app.p_sim)
					% empty everything
						empty_mask_and_InvVar(app);
						empty_D(app);
						app.sim_M_FID = [];
					% update x struct
						check_sim_x(app);
						app.x = app.temp_x;
					% update inverse variance table
						app.InvVarTwTable.Data = [];
						app.InvVarTwTable.ColumnFormat = {[],[],'logical'};
						app.InvVarTwTable.Data(:,1:2) = app.SimTwInvVarTable.Data;
						app.InvVar.Tw = reshape(app.InvVarTwTable.Data(:,2),[1,1,app.x.N2]);
						app.InvVarTwTable.Data(:,3) = 1;
						app.InvVarTwTable.RowName = app.SimTwInvVarTable.RowName;
						app.FeedbackBox.Value = 'Note: Simulating data resets the fitting masks to all 1''s.';
					% update drop downs and inverse variance
						update_plot_dd(app);
						init_InvVar_dd_and_table(app);
						update_mask_and_InvVar(app);
						zero_weight_indicies = ~(app.InvVar_masked.prod);
						nonzero_weight_indicies = find(app.InvVar_masked.prod);
					% generate model and model data
						update_sim_M(app);
					% simulate noise
						switch app.x.num_type
							case 'real'
								app.sim_noise = app.GaussianNoiseStdDevSpinner.Value*randn(size(app.sim_M_FID));
							case 'complex'
								app.sim_noise = app.GaussianNoiseStdDevSpinner.Value*(randn(size(app.sim_M_FID))+1i*randn(size(app.sim_M_FID)));
						end
						app.sim_noise(nonzero_weight_indicies) = app.sim_noise(nonzero_weight_indicies)./sqrt(app.InvVar_masked.prod(nonzero_weight_indicies));
						app.sim_noise(zero_weight_indicies) = 0;
					% add noise to model data
						app.D_FID = app.sim_M_FID + app.sim_noise;
					% FFT to compute 2D spectra
						update_D(app);
						update_M(app);
					% update all plots
						update_all_plots(app);
					% update simulation table
						app.SimParamTable = ParamStruct2ParamTable(app,app.SimParamTable,app.p_sim);
						app.is_simulated = true;
					% update predicted fit
						f = @(x,p) ILS_M(x,p);
						aux = ILS_initialize_aux(app.p_sim);
						J = Jacobian_f(f,app.x,app.p_sim,aux);
						invVar = reshape(app.InvVar_masked.prod,[app.x.numel,1]);
						dD = reshape(app.sim_noise,[app.x.numel,1]);
						dp = inv(real(J'*(invVar.*J)))*real(J'*(invVar.*dD));
						fn = fieldnames(app.p_sim);
						app.p_predicted_fit = app.p_sim;
						app.CompareTable.Data = [];
						app.CompareTable.ColumnFormat = {[],[],[],[],[],[]};
						k = 0;
						for i=1:length(fn)
							if app.p_sim.(fn{i}).var
								k = k+1;
								app.p_predicted_fit.(fn{i}).val = app.p_sim.(fn{i}).val + dp(k);
								app.CompareTable.Data = [app.CompareTable.Data; {fn{i},app.p_sim.(fn{i}).val,app.p_predicted_fit.(fn{i}).val,[],[],app.p_predicted_fit.(fn{i}).units}];
							else
								app.CompareTable.Data = [app.CompareTable.Data; {fn{i},app.p_sim.(fn{i}).val,[],[],[],app.p_sim.(fn{i}).units}];
							end
						end
						app.CompareTable.RowName = 1:length(fn);
				end
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Value changing function: NoPumpPointsSpinner
        function NoPumpPointsSpinnerValueChanging(app, event)
            check_sim_x(app);
        end

        % Value changing function: StepSizepsSpinner
        function StepSizepsSpinnerValueChanging(app, event)
            check_sim_x(app);
        end

        % Value changing function: RotatingFramecm1Spinner
        function RotatingFramecm1SpinnerValueChanging(app, event)
            check_sim_x(app);
        end

        % Value changing function: Startcm1Spinner
        function changing_value_start_probe_axis(app, event)
            check_sim_x(app);
        end

        % Value changing function: Endcm1Spinner
        function changing_value_end_probe_axis(app, event)
            check_sim_x(app);
        end

        % Value changing function: NoProbePointsSpinner
        function changing_value_No_Probe_Points(app, event)
            check_sim_x(app);
        end

        % Callback function
        function SimErrorBoxValueChanged(app, event)
			if strcmp(app.SimErrorBox.Value,'')
				app.SimulateDataButton.Enable = 'off';
			else
				app.SimulateDataButton.Enable = 'on';
			end
        end

        % Value changed function: StepSizepsSpinner
        function StepSizepsSpinnerValueChanged(app, event)
            check_sim_x(app);
        end

        % Value changed function: RotatingFramecm1Spinner
        function RotatingFramecm1SpinnerValueChanged(app, event)
            check_sim_x(app);
        end

        % Value changed function: NoPumpPointsSpinner
        function NoPumpPointsSpinnerValueChanged(app, event)
            check_sim_x(app);
        end

        % Value changed function: Startcm1Spinner
        function changed_value_start_probe_axis(app, event)
            check_sim_x(app);
        end

        % Value changed function: Endcm1Spinner
        function changed_value_end_probe_axis(app, event)
            check_sim_x(app);
        end

        % Value changed function: NoProbePointsSpinner
        function changed_value_No_Probe_Points(app, event)
            check_sim_x(app);
        end

        % Button pushed function: 
        % RandomizeParametersWithinBoundsButton
        function RandomizeParametersWithinBoundsButtonPushed(app, event)
			try
				switch app.model_parameters_tabs.SelectedTab
					case app.SimulationTab
						app.p_sim = ParamTable2ParamStruct(app,app.SimParamTable,app.p);
						aux = ILS_initialize_aux(app.p_sim);
						app.p_sim = ILS_rand_params(app.p_sim,aux);
						app.SimParamTable = ParamStruct2ParamTable(app,app.SimParamTable,app.p_sim);
						app.SimParamTable = check_table(app,app.SimParamTable,app.p_sim);
					case app.ModelTab_2
						app.p = ParamTable2ParamStruct(app,app.ModelParamTable,app.p);
						aux = ILS_initialize_aux(app.p);
						app.p = ILS_rand_params(app.p,aux);
						app.ModelParamTable = ParamStruct2ParamTable(app,app.ModelParamTable,app.p);
						app.ModelParamTable = check_table(app,app.ModelParamTable,app.p);
						update_M(app);
						update_all_plots(app);
				end
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Button pushed function: AddTwPointButton
        function AddTwPointButtonPushed(app, event)
			try
				t = app.SimTwInvVarTable.Data;
				last_row = size(t,1);
				if isempty(app.selected_sim_Tw_rows)
					if last_row == 0
						nr = [0,1];
					else
						nr = t(last_row,:);
					end
					t = [t;nr];
				else
					for i=1:numel(app.selected_sim_Tw_rows)
						nr_index = app.selected_sim_Tw_rows(i) + (i-1);
						nr =  t(nr_index,:);
						if nr_index == 1
							above = [];
						else
							above = t(1:nr_index-1,:);
						end
						below = t(nr_index:size(t,1),:);
						t = [above;nr;below];
					end
				end
				app.SimTwInvVarTable.Data = t;
				app.SimTwInvVarTable.RowName = {1:size(t,1)};
				app.NoSimTwPoints.Value = size(app.SimTwInvVarTable.Data,1);
				check_sim_x(app);
			catch exception
				if strcmp(app.SimErrorBox.Value,'')
					app.SimErrorBox.Value = ['MATLAB ERROR: ',exception.message];
				else
					app.SimErrorBox.Value = [app.SimErrorBox.Value,newline,['MATLAB ERROR: ',exception.message]];
				end
				return
			end
        end

        % Cell selection callback: SimTwInvVarTable
        function SimTwInvVarTableCellSelection(app, event)
            temp = event.Indices;
			app.selected_sim_Tw_rows = temp(:,1);
        end

        % Button pushed function: DeleteTwPointButton
        function DeleteTwPointButtonPushed(app, event)
			try
				if ~isempty(app.selected_sim_Tw_rows)
					if size(app.SimTwInvVarTable.Data,1) > 1
						app.SimTwInvVarTable.Data(app.selected_sim_Tw_rows,:) = [];
						app.SimTwInvVarTable.RowName = {1:length(app.SimTwInvVarTable.Data)};
					end
				end
				app.selected_sim_Tw_rows = [];
				app.NoSimTwPoints.Value = size(app.SimTwInvVarTable.Data,1);
				check_sim_x(app);
			catch exception
				if strcmp(app.SimErrorBox.Value,'')
					app.SimErrorBox.Value = ['MATLAB ERROR: ',exception.message];
				else
					app.SimErrorBox.Value = [app.SimErrorBox.Value,newline,['MATLAB ERROR: ',exception.message]];
				end
				return
			end
        end

        % Display data changed function: SimTwInvVarTable
        function SimTwInvVarTableDisplayDataChanged(app, event)
            app.NoSimTwPoints.Value = size(app.SimTwInvVarTable.Data,1);
        end

        % Button pushed function: LoadTwFilesButton
        function loadTwFilesButtonPushed(app, event)
			try
				load_Tw_files(app);
				check_data_loading(app);
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Cell edit callback: SimTwInvVarTable
        function SimTwInvVarTableCellEdit(app, event)
            check_sim_x(app);
        end

        % Value changed function: TwUnitsDropDown
        function TwUnitsDropDownValueChanged(app, event)
            load_D_Tw_assignment(app);
			check_data_loading(app);
        end

        % Value changed function: TwColumnDropDown
        function TwColumnDropDownValueChanged(app, event)
            load_D_Tw_assignment(app);
			check_data_loading(app);
        end

        % Value changed function: OrientationDropDown
        function OrientationDropDownValueChanged(app, event)
            check_data_loading(app);
        end

        % Button pushed function: CompleteLoadingButton
        function CompleteLoadingButtonPushed(app, event)
			try
				% turn on loading lamp	
					app.LoadingLamp.Color = [0,1,0];app.LoadingLampLabel.Visible = 1;drawnow;
				% if complex-valued, separate real and imaginary parts
					if app.complex_data_file_names == 1
						num_type = 'complex';
						real_files_I = find(contains({app.D_Tw_files.filename},'real'));
						imag_files_I = find(contains({app.D_Tw_files.filename},'imag'));
						[real_files,real_files_I2] = sort(erase({app.D_Tw_files(real_files_I).filename},'real'));
						[imag_files,imag_files_I2] = sort(erase({app.D_Tw_files(imag_files_I).filename},'imag'));
						real_Tw_files = app.D_Tw_files(real_files_I(real_files_I2));
						imag_Tw_files = app.D_Tw_files(imag_files_I(imag_files_I2));
						% sort spectra by ascending Tw
							[B,I] = sort([real_Tw_files.Tw]);
							real_Tw_files = real_Tw_files(I);
							imag_Tw_files = imag_Tw_files(I);
					else
						num_type = 'real';
						% sort spectra by ascending Tw
							[B,I] = sort([app.D_Tw_files.Tw]);
							real_Tw_files = app.D_Tw_files(I);
					end
				% gather inputs needed for generating x struct
					switch app.OrientationDropDown.Value
						case app.OrientationDropDown.Items{1}
							N1 = size(real_Tw_files(1).data,1);
						case app.OrientationDropDown.Items{2}
							N1 = size(real_Tw_files(1).data,2);
					end
					t1_end = (N1-1)*app.TimeSteppsSpinner.Value;
					Tw_arr = [real_Tw_files.Tw];
					N2 = numel(Tw_arr);
					N3 = numel(app.D_load_probe_axis);
					w3_start = app.D_load_probe_axis(1);
					w3_end = app.D_load_probe_axis(N3);
					w1_shift = app.RotatingFramecm1Spinner_2.Value;
				% generate x struct
					app.x = gen_x([0,t1_end],N1,w1_shift,[w3_start,w3_end],N3,Tw_arr,num_type);
				% empty M and D
					empty_M(app);
					empty_D(app);
					empty_mask_and_InvVar(app);
					app.D_FID = zeros(N1,N3,N2);
				% copy spectra into app.D_FID
					for i=1:length(real_Tw_files)
						switch app.OrientationDropDown.Value
							case app.OrientationDropDown.Items{1}
								if strcmp(num_type,'real')
									app.D_FID(:,:,i) = real_Tw_files(i).data;
								else
									app.D_FID(:,:,i) = real_Tw_files(i).data + 1i*imag_Tw_files(i).data;
								end
							case app.OrientationDropDown.Items{2}
								if strcmp(num_type,'real')
									app.D_FID(:,:,i) = transpose(real_Tw_files(i).data);
								else
									app.D_FID(:,:,i) = transpose(real_Tw_files(i).data + 1i*imag_Tw_files(i).data);
								end
						end
					end
				% update inverse variance
					update_plot_dd(app);
					init_InvVar_dd_and_table(app);
					update_mask_and_InvVar(app);
				% update data and model
					update_D(app);
					update_M(app);
				% update plots
					update_all_plots(app);
				% change simulation flag
					app.is_simulated = false;
				% turn off loading lamp
					app.LoadingLamp.Color = [0.94,0.94,0.94];app.LoadingLampLabel.Visible = 0;
					app.complex_data_file_names = 0;
					app.Tw_files_table.Data = [];
					app.LoadTwFilesButton.Enable = 'off';
					app.CompleteLoadingButton.Enable = 'off';
					app.VerifiedCheckBox.Value = 0;
					app.FeedbackBox.Value = 'Don''t forget to check the Mask and Inverse Variance!';
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Button pushed function: GenerateProbeAxisButton
        function GenerateProbeAxisButtonPushed(app, event)
			try
				if ~isempty(app.D_Tw_files)
					if isfield(app.D_Tw_files(1),'data')
						switch app.OrientationDropDown.Value
							case app.OrientationDropDown.Items{1}
								N = size(app.D_Tw_files(1).data,2);
							case app.OrientationDropDown.Items{2}
								N = size(app.D_Tw_files(1).data,1);
						end
						if N<2
							return
						end
						app.D_load_probe_axis = app.FirstPixelcm1Spinner.Value + (0:1:N-1)*app.PixelStepSizecm1Spinner.Value;
					end
				end
				check_data_loading(app);
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Value changed function: VerifiedCheckBox
        function VerifiedCheckBoxValueChanged(app, event)
            check_data_loading(app);
        end

        % Button pushed function: TrimData
        function TrimDataButtonPushed(app, event)
			try
				if ~isempty(app.D_FID)
					w3_trim = sort([app.Probe_mask_dd1.Value,app.Probe_mask_dd2.Value]);
					D_FID_trim = app.D_FID(:,w3_trim(1):w3_trim(2),:);
					x_trim = gen_x([0,app.x.t1(app.x.N1)],app.x.N1,app.x.w1(1),[app.x.w3(w3_trim(1)),app.x.w3(w3_trim(2))],numel(w3_trim(1):w3_trim(2)),app.x.Tw(:),app.x.num_type);
					app.x = x_trim;
					empty_M(app);
					empty_D(app);
					app.mask.probe = [];
					update_D(app,D_FID_trim);
					update_M(app);
					update_plot_dd(app)
					init_InvVar_dd_and_table(app);
					update_mask_and_InvVar(app);
					update_all_plots(app);
				end
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Button pushed function: UpdatePlotsButton
        function UpdatePlotsButtonPushed(app, event)
			try
				update_M(app)
				update_all_plots(app);
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Selection change function: model_parameters_tabs
        function model_parameters_tabsSelectionChanged(app, event)
			try
				selectedTab = app.model_parameters_tabs.SelectedTab;
				switch selectedTab
					case app.ModelTab_2
						if ~isempty(app.p)
							app.p = ParamTable2ParamStruct(app,app.ModelParamTable,app.p);
							app.ModelParamTable = check_table(app,app.ModelParamTable,app.p);
						end
						if isempty(app.ModelParamTable.Data)
							app.SaveToFileButton.Enable = false;
						else
							app.SaveToFileButton.Enable = true;
						end
					case app.InitialGuessTab
						if ~isempty(app.p_init_guess)
							app.p_init_guess = ParamTable2ParamStruct(app,app.InitialGuessParamTable,app.p_init_guess);
							app.InitialGuessParamTable = check_table(app,app.InitialGuessParamTable,app.p_init_guess);
						end
						if isempty(app.InitialGuessParamTable.Data)
							app.SaveToFileButton_2.Enable = false;
						else
							app.SaveToFileButton_2.Enable = true;
						end
					case app.BestFitTab
						if ~isempty(app.p_best_fit)
							app.p_best_fit = ParamTable2ParamStruct(app,app.BestFitParamTable,app.p_best_fit);
							app.BestFitParamTable = check_table(app,app.BestFitParamTable,app.p_best_fit);
						end
						if isempty(app.BestFitParamTable.Data)
							app.SaveToFileButton_5.Enable = false;
						else
							app.SaveToFileButton_5.Enable = true;
						end
					case app.SimulationTab
						if ~isempty(app.p)
							app.CopyFromModelButton.Enable = true;
						end
						if ~isempty(app.p_sim)
							app.p_sim = ParamTable2ParamStruct(app,app.SimParamTable,app.p_sim);
							app.SimParamTable = check_table(app,app.SimParamTable,app.p_sim);
						end
						if isempty(app.SimParamTable.Data)
							app.SaveToFileButton_4.Enable = false;
						else
							app.SaveToFileButton_4.Enable = true;
						end
					case app.CompareSimulationOnlyTab
						if ~isempty(app.p_sim) && ~isempty(app.p_best_fit) && ~isempty(app.p_predicted_fit) && app.is_simulated
							
						end
					otherwise
						app.FeedbackBox.Value = sprintf('ERROR: Tab is undefined for ''%s'' parameter table.',selectedTab.Title);
				end
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Cell edit callback: InvVarTwTable
        function InvVarTwTableCellEdit(app, event)
            update_mask_and_InvVar(app);
        end

        % Button pushed function: CopyFromModelButton
        function CopyFromModelButtonPushed(app, event)
			try
				app.p_sim = [];
				if ~isempty(app.p)
					app.p_sim = app.p;
					app.SimParamTable = ParamStruct2ParamTable(app,app.SimParamTable,app.p_sim);
					check_table(app,app.SimParamTable,app.p_sim);
					if ~isempty(app.x)
						app.SimulateDataButton.Enable = true;
					end
					check_sim_x(app);
				end
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Callback function
        function LoadFromFileButtonPushed(app, event)
			try
				[file,path] = uigetfile('*.mat','Select a File',app.last_path);
				figure(app.UIFigure);
				if file ~= 0
					load([path,file]);
					if exist('p','var')
						app.p_sim;
						app.p_sim = p;
						app.SimParamTable = ParamStruct2ParamTable(app,app.SimParamTable,app.p_sim);
						app.SimParamTable = check_table(app,app.SimParamTable,app.p_sim);
						if ~isempty(app.x)
							app.SimulateDataButton.Enable = true;
						end
						check_sim_x(app);
					end
				end	
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Cell edit callback: SimParamTable
        function SimParamTableCellEdit(app, event)
			try
				app.SimParamTable.RowName = {1:length(app.SimParamTable.Data)};
				app.p_sim = ParamTable2ParamStruct(app,app.SimParamTable,app.p_sim);
				app.SimParamTable = check_table(app,app.SimParamTable,app.p_sim);
				if ~isempty(app.SimParamTable.Data)
					app.SaveToFileButton_4.Enable = true;
				end
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Button pushed function: LoadFromFileButton
        function LoadFromFileButtonPushed2(app, event)
			try
				load_parameters(app,app.ModelTab_2)
				if ~isempty(app.ModelParamTable.Data)
					app.SaveToFileButton.Enable = true;
				end
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Button pushed function: LoadFromFileButton_4
        function LoadFromFileButton_4Pushed(app, event)
			try
				load_parameters(app,app.SimulationTab)
				if ~isempty(app.SimParamTable.Data)
					app.SaveToFileButton_4.Enable = true;
				end
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Button pushed function: SaveToFileButton
        function SaveToFileButtonPushed(app, event)
            save_parameters(app,app.ModelTab_2)
        end

        % Button pushed function: SaveToFileButton_2
        function SaveToFileButton_2Pushed(app, event)
            save_parameters(app,app.InitialGuessTab)
        end

        % Button pushed function: SaveToFileButton_4
        function SaveToFileButton_4Pushed(app, event)
            save_parameters(app,app.SimulationTab)
        end

        % Button pushed function: SaveToFileButton_5
        function SaveToFileButton_5Pushed(app, event)
            save_parameters(app,app.BestFitTab)
        end

        % Value changed function: ProbeMin
        function ProbeMinValueChanged(app, event)
            update_2D_plots(app);
        end

        % Value changed function: ProbeMax
        function ProbeMaxValueChanged(app, event)
            update_2D_plots(app);
        end

        % Value changed function: PumpMin
        function PumpMinValueChanged(app, event)
            update_2D_plots(app);
        end

        % Value changed function: PumpMax
        function PumpMaxValueChanged(app, event)
            update_2D_plots(app);
        end

        % Value changed function: StopFittingButton
        function StopFittingButtonValueChanged(app, event)
            value = app.StopFittingButton.Value;
			if value == 1 && app.is_fitting == 1
				app.FeedbackBox.Value = 'Stop sequence initiated. Please wait while program completes iteration...';
			elseif value == 0 && app.is_fitting == 1
				app.FeedbackBox.Value = 'Model fitting in progress...';
			end
        end

        % Value changed function: PauseFittingButton
        function PauseFittingButtonValueChanged(app, event)
            value = app.PauseFittingButton.Value;
			if value == 1 && app.is_fitting == 1
				app.FeedbackBox.Value = 'Model fitting paused...';
			elseif value == 0 && app.is_fitting == 1
				app.FeedbackBox.Value = 'Model fitting in progress...';
			end
        end

        % Menu selected function: ModelFittingGitHubWebpageMenu
        function ModelFittingGitHubWebpageMenuSelected(app, event)
            web('https://github.com/kevin-robben/model-fitting','-browser')
        end

        % Menu selected function: ModelFittingManuscriptWebpageMenu
        function ModelFittingManuscriptWebpageMenuSelected(app, event)
            web('https://github.com/kevin-robben/model-fitting','-browser')
        end

        % Menu selected function: CiteMenu
        function CiteMenuSelected(app, event)
            f = figure;set(f,'Position',[400,500,700,200],'MenuBar','none','NumberTitle','off','ToolBar','none','Name','Citation');
			str = ['Least-Squares Fitting of Multidimensional Spectra to Kubo Lineshape Models',newline,'Robben, Kevin C.; Cheatum, Christopher M.; (2021) Submitted to J. Phys. Chem. B'];
			annotation(f,'textbox',[0.05 0.4 0.9 0.50],'VerticalAlignment','middle','HorizontalAlignment','center','String',str,'LineStyle','none','FontSize',12,'FitBoxToText','off');
			push_button = uicontrol(f,'Style','pushbutton','Position',[275 20 150 30],'min',0,'max',2,'enable','on','String','Copy to Clipboard');
			push_button.Callback = @(hObject,eventdata) (clipboard('copy',str));
        end

        % Button pushed function: LoadTwAxis
        function LoadTwAxisButtonPushed(app, event)
			try
				[file,path] = uigetfile('*.csv','Select a File',[app.last_path]);
				figure(app.UIFigure);
				if file ~= 0
					app.last_path = path;
					tbl = table2array(readtable([path,file]));
					app.FeedbackBox.Value = '';
					if isempty('tbl')
						app.FeedbackBox.Value = 'ERROR: Loading Tw Axis failed. Table is empty.';
						return
					elseif size(tbl,2) ~= 2
						app.FeedbackBox.Value = 'ERROR: Loading Tw Axis failed. Table must have 2 columns.';
						return
					else
						app.SimTwInvVarTable.Data = [];
						app.SimTwInvVarTable.Data = tbl;
						app.SimTwInvVarTable.RowName = {1:size(tbl,1)};
						app.NoSimTwPoints.Value = size(app.SimTwInvVarTable.Data,1);
						check_sim_x(app);
					end
				end
			catch exception
				if strcmp(app.SimErrorBox.Value,'')
					app.SimErrorBox.Value = ['MATLAB ERROR: ',exception.message];
				else
					app.SimErrorBox.Value = [app.SimErrorBox.Value,newline,['MATLAB ERROR: ',exception.message]];
				end
				return
			end
        end

        % Button pushed function: SaveTwAxis
        function SaveTwAxisButtonPushed(app, event)
			try
				tbl = array2table(app.SimTwInvVarTable.Data);
				[file,path] = uiputfile('*.csv','Save As',[app.last_path,'Sim Tw Axis.csv']);
				figure(app.UIFigure)
				if file ~= 0
						writetable(tbl,[path,file],"WriteVariableNames",false);
				end
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Button pushed function: ClearFeedbackButton
        function ClearFeedbackButtonPushed(app, event)
            app.FeedbackBox.Value = '';
        end

        % Cell selection callback: InitialGuessParamTable
        function InitialGuessParamTableCellSelection(app, event)
			update_feedback_box(app,'Warning: Only parameters under the ''Model'' and ''Simulation'' tabs are editable.');
        end

        % Cell selection callback: BestFitParamTable
        function BestFitParamTableCellSelection(app, event)
			update_feedback_box(app,'Warning: Only parameters under the ''Model'' and ''Simulation'' tabs are editable.');
        end

        % Cell selection callback: CompareTable
        function CompareTableCellSelection(app, event)
			update_feedback_box(app,'Warning: Only parameters under the ''Model'' and ''Simulation'' tabs are editable.');
        end

        % Menu selected function: Tutorial1
        function Tutorial1MenuSelected(app, event)
            web('https://youtu.be/phpBHMjw8qY','-browser')
        end

        % Menu selected function: Tutorial2
        function Tutorial2MenuSelected(app, event)
            web('https://youtu.be/EwSMk4gvQH0','-browser')
        end

        % Menu selected function: Tutorial3
        function Tutorial3MenuSelected(app, event)
            web('https://youtu.be/OIr7Bj7gT8E','-browser')
        end

        % Menu selected function: Tutorial4
        function Tutorial4MenuSelected(app, event)
            web('https://youtu.be/ylEwL2eukR4','-browser')
        end

        % Menu selected function: Tutorial5
        function Tutorial5MenuSelected(app, event)
            web('https://youtu.be/VkyHm0bGlH8','-browser')
        end

        % Button pushed function: CopyFromDataButton
        function CopyFromDataButtonPushed(app, event)
			try
				if ~isempty(app.x)
					app.Startcm1Spinner.Value = app.x.w3(1);
					app.Endcm1Spinner.Value = app.x.w3(app.x.N3);
					app.NoProbePointsSpinner.Value = app.x.N3;
					switch app.x.num_type
						case 'real'
							app.NumericTypeDropDown.Value = app.NumericTypeDropDown.ItemsData{1};
						case 'complex'
							app.NumericTypeDropDown.Value = app.NumericTypeDropDown.ItemsData{2};
					end
					app.RotatingFramecm1Spinner.Value = app.x.w1(1);
					app.StepSizepsSpinner.Value = app.x.t1(2) - app.x.t1(1);
					app.NoPumpPointsSpinner.Value = app.x.N1;
					app.SimTwInvVarTable.Data = app.InvVarTwTable.Data(:,1:2);
					check_sim_x(app);
					app.FeedbackBox.Value = 'Note: Simulating data resets the fitting masks to all 1''s.';
				else
					app.FeedbackBox.Value = 'ERROR: No data in memory to copy from.';
				end
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Button pushed function: LoadInvVarFromFileButton
        function LoadInvVarFromFileButtonPushed(app, event)
			try
				[file,path] = uigetfile({'*.dat;*.txt'},'Select a File',[app.last_path]);
				figure(app.UIFigure);
				if file ~= 0
					if exist([path,file],"file")
						app.last_path = path;
						InvVar_Tw = load([path,file]);
						if isempty(InvVar_Tw)
							app.FeedbackBox.Value = 'Error: Imported file is empty.';
							return;
						end
						if any(isnan(InvVar_Tw)) || any(isinf(InvVar_Tw))
							app.FeedbackBox.Value = 'Error: Imported file contains NaN or Inf data types.';
							return;
						end
						if isempty(app.InvVar.Tw) && isempty(app.x)
							app.InvVar.Tw = reshape(InvVar_Tw,[1,1,numel(InvVar_Tw)]);
						elseif ~isempty(app.x) && all(numel(InvVar_Tw) == app.x.N2)
							app.InvVar.Tw = reshape(InvVar_Tw,[1,1,numel(InvVar_Tw)]);
						else
							app.FeedbackBox.Value = 'Error: Size of imported file is incompatible with current x struct.';
							return;
						end
						init_InvVar_dd_and_table(app);
						update_mask_and_InvVar(app);
					end
				end
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Button pushed function: GatherWaitingTimeFileNamesButton
        function GatherWaitingTimeFileNamesButtonPushed(app, event)
			try
				[file,path] = uigetfile({'*.dat;*.txt'},'Select a File',[app.last_path],'MultiSelect','on');
				figure(app.UIFigure);
				if isnumeric(file)
					if file == 0
						return;
					end
				end
				app.last_path = path;
				app.LoadingLamp.Color = [0,1,0];app.LoadingLampLabel.Visible = 1;drawnow;
				app.VerifiedCheckBox.Value = 0;
				app.LoadTwFilesButton.Enable = 'off';
				app.CompleteLoadingButton.Enable = 'off';
				app.D_load_probe_axis = [];
				app.D_Tw_files = [];
				gather_Tw_file_list(app,path,file);
				check_data_loading(app);
				app.LoadingLamp.Color = [0.94,0.94,0.94];app.LoadingLampLabel.Visible = 0;
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Button pushed function: LoadProbeAxisFileButton
        function LoadProbeAxisFileButtonPushed(app, event)
			try
				app.LoadingLamp.Color = [0,1,0];app.LoadingLampLabel.Visible = 1;drawnow;
				[file,path] = uigetfile({'*.calib';'*.dat;*.txt'},'Select a File',[app.last_path]);
				figure(app.UIFigure);
				if file ~= 0
					app.last_path = path;
					app.D_load_probe_axis = load([path,file]);
				end
				check_data_loading(app);
				app.LoadingLamp.Color = [0.94,0.94,0.94];app.LoadingLampLabel.Visible = 0;
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
			try
				[file,path] = uigetfile('*.mat','Select a File',[app.last_path]);
				figure(app.UIFigure);
				if file ~= 0
					if exist([path,file],"file")
					% load data
						app.last_path = path;
						app.LoadingLamp.Color = [0,1,0];app.LoadingLampLabel.Visible = 1;drawnow;
						load([path,file]);
						if exist('D_FID',"var") && exist('x',"var")
							empty_mask_and_InvVar(app);
							app.D_FID = D_FID;
							app.x = x;
							update_D(app);
							update_M(app);
							app.LoadingLamp.Color = [0.94,0.94,0.94];app.LoadingLampLabel.Visible = 0;
						% update plot drop downs
							update_plot_dd(app);
						% update InvVar
							if exist('mask',"var")
								app.mask = mask;
							end
							if exist('InvVar',"var")
								app.InvVar = InvVar;
							end
							if exist('InvVar_masked',"var")
								app.InvVar_masked = InvVar_masked;
							end
							init_InvVar_dd_and_table(app);
							update_mask_and_InvVar(app);
							update_all_plots(app);
						% change simulation flag
							app.is_simulated = false;
						end
						app.LoadingLamp.Color = [0.94,0.94,0.94];app.LoadingLampLabel.Visible = 0;
					end
				end
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
			try
				[file,path] = uiputfile('*.mat','Save As',[app.last_path,'data (matlab-ready).mat']);
				figure(app.UIFigure);
				if file ~= 0
					app.last_path = path;
					D_FID = app.D_FID;
					x = app.x;
					mask = app.mask;
					InvVar = app.InvVar;
					InvVar_masked = app.InvVar_masked;
					save([path,file],'D_FID','x','mask','InvVar','InvVar_masked');
				end
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Button pushed function: AddKuboButton_2
        function AddKuboButton_2Pushed(app, event)
			try
				app.SimParamTable = add_kubo(app,app.SimParamTable);
				app.p_sim = ParamTable2ParamStruct(app,app.SimParamTable,app.p_sim);
				app.SimParamTable = check_table(app,app.SimParamTable,app.p_sim);
				if app.AutoUpdatePlotsCheckBox.Value
					update_M(app);
					update_all_plots(app);
				end
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Button pushed function: RemoveKuboButton_2
        function RemoveKuboButton_2Pushed(app, event)
			try
				app.SimParamTable = remove_kubo(app,app.SimParamTable);
				app.SimParamTable.RowName = {1:length(app.SimParamTable.Data)};
				app.p_sim = ParamTable2ParamStruct(app,app.SimParamTable,app.p_sim);
				app.SimParamTable = check_table(app,app.SimParamTable,app.p_sim);
				if app.AutoUpdatePlotsCheckBox.Value
					update_M(app);
					update_all_plots(app);
				end
			catch exception
				update_feedback_box(app,exception);
			end
        end

        % Cell selection callback: SimParamTable
        function SimParamTableCellSelection(app, event)
            temp = event.Indices;
			app.selected_param_rows = temp(:,1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Model Fitting GUI';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu_2 = uimenu(app.FileMenu);
            app.LoadMenu_2.Text = 'Load';
            app.DataMenu = uimenu(app.LoadMenu_2);
            app.DataMenu.Text = 'Data';
            app.AxesSeparateMenu = uimenu(app.DataMenu);
            app.AxesSeparateMenu.Text = 'Axes Separate';
            app.GathetTwFileNamesMenu = uimenu(app.AxesSeparateMenu);
            app.GathetTwFileNamesMenu.Text = 'Gathet Tw File Names';
            app.LoadProbeAxisMenu_2 = uimenu(app.AxesSeparateMenu);
            app.LoadProbeAxisMenu_2.Text = 'Load Probe Axis';
            app.MATLABReadyFormatMenu = uimenu(app.DataMenu);
            app.MATLABReadyFormatMenu.Text = 'MATLAB-Ready Format';
            app.SaveMenu_2 = uimenu(app.FileMenu);
            app.SaveMenu_2.Text = 'Save';
            app.DataMenu_2 = uimenu(app.SaveMenu_2);
            app.DataMenu_2.Text = 'Data';
            app.MATLABReadyFormatMenu_2 = uimenu(app.DataMenu_2);
            app.MATLABReadyFormatMenu_2.Text = 'MATLAB-Ready Format';
            app.ManuscriptMenu = uimenu(app.UIFigure);
            app.ManuscriptMenu.Text = 'Manuscript';
            app.ModelFittingManuscriptWebpageMenu = uimenu(app.ManuscriptMenu);
            app.ModelFittingManuscriptWebpageMenu.MenuSelectedFcn = createCallbackFcn(app, @ModelFittingManuscriptWebpageMenuSelected, true);
            app.ModelFittingManuscriptWebpageMenu.Text = 'Model Fitting Manuscript (Webpage)';
            app.CiteMenu = uimenu(app.ManuscriptMenu);
            app.CiteMenu.MenuSelectedFcn = createCallbackFcn(app, @CiteMenuSelected, true);
            app.CiteMenu.Text = 'Cite';
            app.GitHubMenu = uimenu(app.UIFigure);
            app.GitHubMenu.Text = 'GitHub';
            app.ModelFittingGitHubWebpageMenu = uimenu(app.GitHubMenu);
            app.ModelFittingGitHubWebpageMenu.MenuSelectedFcn = createCallbackFcn(app, @ModelFittingGitHubWebpageMenuSelected, true);
            app.ModelFittingGitHubWebpageMenu.Text = 'Model Fitting GitHub (Webpage)';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.YoutubeTutorialsMenu = uimenu(app.HelpMenu);
            app.YoutubeTutorialsMenu.Text = 'Youtube Tutorials';
            app.Tutorial1 = uimenu(app.YoutubeTutorialsMenu);
            app.Tutorial1.MenuSelectedFcn = createCallbackFcn(app, @Tutorial1MenuSelected, true);
            app.Tutorial1.Text = 'Tutorial 1: Installing the Model Fitting App';
            app.Tutorial2 = uimenu(app.YoutubeTutorialsMenu);
            app.Tutorial2.MenuSelectedFcn = createCallbackFcn(app, @Tutorial2MenuSelected, true);
            app.Tutorial2.Text = 'Tutorial 2: Loading Data';
            app.Tutorial3 = uimenu(app.YoutubeTutorialsMenu);
            app.Tutorial3.MenuSelectedFcn = createCallbackFcn(app, @Tutorial3MenuSelected, true);
            app.Tutorial3.Text = 'Tutorial 3: Mask and Inverse Variance';
            app.Tutorial4 = uimenu(app.YoutubeTutorialsMenu);
            app.Tutorial4.MenuSelectedFcn = createCallbackFcn(app, @Tutorial4MenuSelected, true);
            app.Tutorial4.Text = 'Tutorial 4: Load Parameters, Fit to Model';
            app.Tutorial5 = uimenu(app.YoutubeTutorialsMenu);
            app.Tutorial5.MenuSelectedFcn = createCallbackFcn(app, @Tutorial5MenuSelected, true);
            app.Tutorial5.Text = 'Tutorial 5: Simulate Data, More Model Fitting';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.LoadingLampLabel = uilabel(app.DataTab);
            app.LoadingLampLabel.Text = 'Loading...';
            app.LoadingLamp = uilamp(app.DataTab);
            app.LoadingLamp.Tooltip = {'Please wait while program is loading data'};
            app.MATLABReadyFormatPanel = uipanel(app.DataTab);
            app.MATLABReadyFormatPanel.Title = 'MATLAB-Ready Format';
            app.SaveDataButton = uibutton(app.MATLABReadyFormatPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.LoadDataButton = uibutton(app.MATLABReadyFormatPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.LoadDataFromdatortxtFormatPanel = uipanel(app.DataTab);
            app.LoadDataFromdatortxtFormatPanel.Title = 'Load Data From .dat or .txt Format';
            app.TextArea_3 = uitextarea(app.LoadDataFromdatortxtFormatPanel);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'Note: Data must be time-domain pump, frequency-domain probe for loading.'};
            app.LoadTwFilesButton = uibutton(app.LoadDataFromdatortxtFormatPanel, 'push');
            app.LoadTwFilesButton.ButtonPushedFcn = createCallbackFcn(app, @loadTwFilesButtonPushed, true);
            app.LoadTwFilesButton.Enable = 'off';
            app.LoadTwFilesButton.Tooltip = {'Press to load waiting-time spectra from files into memory'};
            app.LoadTwFilesButton.Text = 'Load Tw Files';
            app.CompleteLoadingButton = uibutton(app.LoadDataFromdatortxtFormatPanel, 'push');
            app.CompleteLoadingButton.ButtonPushedFcn = createCallbackFcn(app, @CompleteLoadingButtonPushed, true);
            app.CompleteLoadingButton.Enable = 'off';
            app.CompleteLoadingButton.Tooltip = {'Press to complete data loading when enabled'};
            app.CompleteLoadingButton.Text = 'Complete Loading';
            app.GatherWaitingTimeFileNamesButton = uibutton(app.LoadDataFromdatortxtFormatPanel, 'push');
            app.GatherWaitingTimeFileNamesButton.ButtonPushedFcn = createCallbackFcn(app, @GatherWaitingTimeFileNamesButtonPushed, true);
            app.GatherWaitingTimeFileNamesButton.Text = 'Gather Waiting-Time File Names';
            app.TwUnitsDropDownLabel = uilabel(app.LoadDataFromdatortxtFormatPanel);
            app.TwUnitsDropDownLabel.Text = 'Tw Units';
            app.TwUnitsDropDown = uidropdown(app.LoadDataFromdatortxtFormatPanel);
            app.TwUnitsDropDown.Items = {'fs', 'ps', 'ns'};
            app.TwUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @TwUnitsDropDownValueChanged, true);
            app.TwUnitsDropDown.Tooltip = {'Units associated with the waiting time in the file names'};
            app.TwUnitsDropDown.Value = 'fs';
            app.TwColumnDropDownLabel = uilabel(app.LoadDataFromdatortxtFormatPanel);
            app.TwColumnDropDownLabel.Text = 'Tw Column';
            app.TwColumnDropDown = uidropdown(app.LoadDataFromdatortxtFormatPanel);
            app.TwColumnDropDown.ValueChangedFcn = createCallbackFcn(app, @TwColumnDropDownValueChanged, true);
            app.TwColumnDropDown.Tooltip = {'Column associated with the waiting time in the table below'};
            app.OrientationDropDownLabel = uilabel(app.LoadDataFromdatortxtFormatPanel);
            app.OrientationDropDownLabel.Text = 'Orientation';
            app.OrientationDropDown = uidropdown(app.LoadDataFromdatortxtFormatPanel);
            app.OrientationDropDown.Items = {'Probe is vertical, pump is horizontal', 'Probe is horizontal, pump is vertical'};
            app.OrientationDropDown.ValueChangedFcn = createCallbackFcn(app, @OrientationDropDownValueChanged, true);
            app.OrientationDropDown.Tooltip = {'Orientation of spectra in data files'};
            app.OrientationDropDown.Value = 'Probe is vertical, pump is horizontal';
            app.PumpAxisPanel_2 = uipanel(app.LoadDataFromdatortxtFormatPanel);
            app.PumpAxisPanel_2.Title = 'Pump Axis';
            app.VerifiedCheckBox = uicheckbox(app.PumpAxisPanel_2);
            app.VerifiedCheckBox.ValueChangedFcn = createCallbackFcn(app, @VerifiedCheckBoxValueChanged, true);
            app.VerifiedCheckBox.Tooltip = {'Check to verify that the rotating frame and time step of the pump axis are correct'};
            app.VerifiedCheckBox.Text = 'Verified';
            app.RotatingFramecm1Spinner_2Label = uilabel(app.PumpAxisPanel_2);
            app.RotatingFramecm1Spinner_2Label.Text = 'Rotating Frame (cm-1)';
            app.RotatingFramecm1Spinner_2 = uispinner(app.PumpAxisPanel_2);
            app.RotatingFramecm1Spinner_2.Step = 5;
            app.RotatingFramecm1Spinner_2.UpperLimitInclusive = 'off';
            app.RotatingFramecm1Spinner_2.Limits = [0 Inf];
            app.RotatingFramecm1Spinner_2.Tooltip = {'Select the starting frequency of the pump axis'};
            app.RotatingFramecm1Spinner_2.Value = 1800;
            app.TimeSteppsSpinnerLabel = uilabel(app.PumpAxisPanel_2);
            app.TimeSteppsSpinnerLabel.Text = 'Time Step (ps)';
            app.TimeSteppsSpinner = uispinner(app.PumpAxisPanel_2);
            app.TimeSteppsSpinner.Step = 0.001;
            app.TimeSteppsSpinner.LowerLimitInclusive = 'off';
            app.TimeSteppsSpinner.UpperLimitInclusive = 'off';
            app.TimeSteppsSpinner.Limits = [0 Inf];
            app.TimeSteppsSpinner.Tooltip = {'Select the time step along the pump axis'};
            app.TimeSteppsSpinner.Value = 0.024;
            app.ProbeAxisPanel_2 = uipanel(app.LoadDataFromdatortxtFormatPanel);
            app.ProbeAxisPanel_2.Title = 'Probe Axis';
            app.GenerateProbeAxisButton = uibutton(app.ProbeAxisPanel_2, 'push');
            app.GenerateProbeAxisButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateProbeAxisButtonPushed, true);
            app.GenerateProbeAxisButton.Tooltip = {'Press to generate a probe axis if one isn''t available to load from file'};
            app.GenerateProbeAxisButton.Text = {'Generate'; 'Probe Axis'};
            app.FirstPixelcm1SpinnerLabel = uilabel(app.ProbeAxisPanel_2);
            app.FirstPixelcm1SpinnerLabel.Text = 'First Pixel (cm-1)';
            app.FirstPixelcm1Spinner = uispinner(app.ProbeAxisPanel_2);
            app.FirstPixelcm1Spinner.UpperLimitInclusive = 'off';
            app.FirstPixelcm1Spinner.Limits = [0 Inf];
            app.FirstPixelcm1Spinner.Tooltip = {'Select the starting frequency of the probe axis'};
            app.FirstPixelcm1Spinner.Value = 2000;
            app.PixelStepSizecm1SpinnerLabel = uilabel(app.ProbeAxisPanel_2);
            app.PixelStepSizecm1SpinnerLabel.Text = 'Pixel Step Size (cm-1)';
            app.PixelStepSizecm1Spinner = uispinner(app.ProbeAxisPanel_2);
            app.PixelStepSizecm1Spinner.LowerLimitInclusive = 'off';
            app.PixelStepSizecm1Spinner.UpperLimitInclusive = 'off';
            app.PixelStepSizecm1Spinner.Limits = [0 Inf];
            app.PixelStepSizecm1Spinner.Tooltip = {'Select the frequency step along the probe axis'};
            app.PixelStepSizecm1Spinner.Value = 0.3;
            app.LoadProbeAxisFileButton = uibutton(app.ProbeAxisPanel_2, 'push');
            app.LoadProbeAxisFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProbeAxisFileButtonPushed, true);
            app.LoadProbeAxisFileButton.Text = 'Load From File';
            app.Tw_files_table = uitable(app.LoadDataFromdatortxtFormatPanel);
            app.Tw_files_table.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Tw_files_table.RowName = {};
            app.Tw_files_table.ColumnSortable = true;
            app.MaskandInverseVarianceTab = uitab(app.TabGroup);
            app.MaskandInverseVarianceTab.Title = 'Mask and Inverse Variance';
            app.PumpAxisFittingRangePanel = uipanel(app.MaskandInverseVarianceTab);
            app.PumpAxisFittingRangePanel.Title = 'Pump Axis Fitting Range';
            app.MinLabel = uilabel(app.PumpAxisFittingRangePanel);
            app.MinLabel.Text = 'Min.';
            app.Pump_mask_dd1 = uidropdown(app.PumpAxisFittingRangePanel);
            app.Pump_mask_dd1.ValueChangedFcn = createCallbackFcn(app, @Pump_mask_dd1ValueChanged, true);
            app.Pump_mask_dd1.Tooltip = {'The lower bound for model fitting along the pump axis'};
            app.MaxLabel_2 = uilabel(app.PumpAxisFittingRangePanel);
            app.MaxLabel_2.Text = 'Max.';
            app.Pump_mask_dd2 = uidropdown(app.PumpAxisFittingRangePanel);
            app.Pump_mask_dd2.ValueChangedFcn = createCallbackFcn(app, @Pump_mask_dd2ValueChanged, true);
            app.Pump_mask_dd2.Tooltip = {'The upper bound for model fitting along the pump axis'};
            app.WaitingTimeAxisFittingRangeandInverseVariancePanel = uipanel(app.MaskandInverseVarianceTab);
            app.WaitingTimeAxisFittingRangeandInverseVariancePanel.Title = 'Waiting Time Axis Fitting Range and Inverse Variance';
            app.InvVarTwTable = uitable(app.WaitingTimeAxisFittingRangeandInverseVariancePanel);
            app.InvVarTwTable.ColumnName = {'Tw (ps)'; 'Inverse Variance'; 'Include in Fit?'};
            app.InvVarTwTable.RowName = {};
            app.InvVarTwTable.ColumnEditable = [false false true];
            app.InvVarTwTable.CellEditCallback = createCallbackFcn(app, @InvVarTwTableCellEdit, true);
            app.InvVarTwTable.Tooltip = {''};
            app.LoadInvVarFromFileButton = uibutton(app.WaitingTimeAxisFittingRangeandInverseVariancePanel, 'push');
            app.LoadInvVarFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadInvVarFromFileButtonPushed, true);
            app.LoadInvVarFromFileButton.Text = 'Load Inv. Var. From File';
            app.ProbeAxisFittingRangePanel = uipanel(app.MaskandInverseVarianceTab);
            app.ProbeAxisFittingRangePanel.Title = 'Probe Axis Fitting Range';
            app.MinLabel_2 = uilabel(app.ProbeAxisFittingRangePanel);
            app.MinLabel_2.Text = 'Min.';
            app.Probe_mask_dd1 = uidropdown(app.ProbeAxisFittingRangePanel);
            app.Probe_mask_dd1.ValueChangedFcn = createCallbackFcn(app, @Probe_mask_dd1ValueChanged, true);
            app.Probe_mask_dd1.Tooltip = {'The upper bound for model fitting along the probe axis'};
            app.MaxLabel = uilabel(app.ProbeAxisFittingRangePanel);
            app.MaxLabel.Text = 'Max.';
            app.Probe_mask_dd2 = uidropdown(app.ProbeAxisFittingRangePanel);
            app.Probe_mask_dd2.ValueChangedFcn = createCallbackFcn(app, @Probe_mask_dd2ValueChanged, true);
            app.Probe_mask_dd2.Tooltip = {'The upper bound for model fitting along the probe axis'};
            app.TrimData = uibutton(app.ProbeAxisFittingRangePanel, 'push');
            app.TrimData.ButtonPushedFcn = createCallbackFcn(app, @TrimDataButtonPushed, true);
            app.TrimData.Tooltip = {'Press to trim away excess data along the probe axis outside of the fitting range'};
            app.TrimData.Text = {'Trim Probe'; 'Axis to Mask'};
            app.NumberofPointsEditFieldLabel = uilabel(app.ProbeAxisFittingRangePanel);
            app.NumberofPointsEditFieldLabel.Text = 'Number of Points';
            app.NumberofPointsEditField = uieditfield(app.ProbeAxisFittingRangePanel, 'numeric');
            app.TextArea_2 = uitextarea(app.MaskandInverseVarianceTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'What is the Mask?'; 'The mask is a binary array (of 1''s and 0''s) identical in size to the data that effectively defines the "fitting range" or what data are included in the fit.'; ''; 'What is the Inverse Variance?'; 'The Generalized Least-Squares Method requires every individual datum be weighted by its expected inverse variance of noise. In the simplest case, the inverse variance is proportional to the number of times a spectrum is averaged.'};
            app.ModelandFittingTab = uitab(app.TabGroup);
            app.ModelandFittingTab.Title = 'Model and Fitting';
            app.UpdatePlotsButton = uibutton(app.ModelandFittingTab, 'push');
            app.UpdatePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed, true);
            app.UpdatePlotsButton.Tooltip = {'Update model plots based on parameters below'};
            app.UpdatePlotsButton.Text = 'Update Plots';
            app.AutoUpdatePlotsCheckBox = uicheckbox(app.ModelandFittingTab);
            app.AutoUpdatePlotsCheckBox.Tooltip = {'Automatically update plots when a parameter is changed.'};
            app.AutoUpdatePlotsCheckBox.Text = 'Auto-Update Plots';
            app.RandomizeParametersWithinBoundsButton = uibutton(app.ModelandFittingTab, 'push');
            app.RandomizeParametersWithinBoundsButton.ButtonPushedFcn = createCallbackFcn(app, @RandomizeParametersWithinBoundsButtonPushed, true);
            app.RandomizeParametersWithinBoundsButton.Tooltip = {'Randomizes the ''Value'' of every variable parameter, sampling from '; 'a uniform distribution within the parameter boundaries'};
            app.RandomizeParametersWithinBoundsButton.Text = {'Randomize'; 'Parameters'; 'Within Bounds'};
            app.model_parameters_tabs = uitabgroup(app.ModelandFittingTab);
            app.model_parameters_tabs.SelectionChangedFcn = createCallbackFcn(app, @model_parameters_tabsSelectionChanged, true);
            app.ModelTab_2 = uitab(app.model_parameters_tabs);
            app.ModelTab_2.Title = 'Model';
            app.AddKuboButton = uibutton(app.ModelTab_2, 'push');
            app.AddKuboButton.ButtonPushedFcn = createCallbackFcn(app, @AddKuboButtonPushed, true);
            app.AddKuboButton.Text = 'Add Kubo';
            app.RemoveKuboButton = uibutton(app.ModelTab_2, 'push');
            app.RemoveKuboButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveKuboButtonPushed, true);
            app.RemoveKuboButton.Text = 'Remove Kubo';
            app.ModelParamTable = uitable(app.ModelTab_2);
            app.ModelParamTable.ColumnName = {'Field Name'; 'Type'; 'Value'; 'Lower Bound'; 'Upper Bound'; 'Units'; 'Plot Label'; 'Std. Dev.'; '95% C.I.'};
            app.ModelParamTable.RowName = {};
            app.ModelParamTable.ColumnSortable = [true true false false false false false false false];
            app.ModelParamTable.ColumnEditable = [false true true true true false false false false];
            app.ModelParamTable.CellEditCallback = createCallbackFcn(app, @ModelParamTableCellEdit, true);
            app.ModelParamTable.CellSelectionCallback = createCallbackFcn(app, @ModelParamTableCellSelection, true);
            app.LoadFromFileButton = uibutton(app.ModelTab_2, 'push');
            app.LoadFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFromFileButtonPushed2, true);
            app.LoadFromFileButton.Tooltip = {'Load parameters from a .csv file (requires proper formatting)'};
            app.LoadFromFileButton.Text = 'Load From File';
            app.SaveToFileButton = uibutton(app.ModelTab_2, 'push');
            app.SaveToFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveToFileButtonPushed, true);
            app.SaveToFileButton.Enable = 'off';
            app.SaveToFileButton.Tooltip = {'Save parameters below to .csv file'};
            app.SaveToFileButton.Text = 'Save To File';
            app.InitialGuessTab = uitab(app.model_parameters_tabs);
            app.InitialGuessTab.Title = 'Initial Guess';
            app.InitialGuessParamTable = uitable(app.InitialGuessTab);
            app.InitialGuessParamTable.ColumnName = {'Field Name'; 'Type'; 'Value'; 'Lower Bound'; 'Upper Bound'; 'Units'; 'Plot Label'; 'Std. Dev.'; '95% C.I.'};
            app.InitialGuessParamTable.RowName = {};
            app.InitialGuessParamTable.ColumnSortable = [true true false false false false false false false];
            app.InitialGuessParamTable.CellSelectionCallback = createCallbackFcn(app, @InitialGuessParamTableCellSelection, true);
            app.SaveToFileButton_2 = uibutton(app.InitialGuessTab, 'push');
            app.SaveToFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveToFileButton_2Pushed, true);
            app.SaveToFileButton_2.Enable = 'off';
            app.SaveToFileButton_2.Tooltip = {'Save parameters below to .csv file'};
            app.SaveToFileButton_2.Text = 'Save To File';
            app.BestFitTab = uitab(app.model_parameters_tabs);
            app.BestFitTab.Title = 'Best Fit';
            app.BestFitParamTable = uitable(app.BestFitTab);
            app.BestFitParamTable.ColumnName = {'Field Name'; 'Type'; 'Value'; 'Lower Bound'; 'Upper Bound'; 'Units'; 'Plot Label'; 'Std. Dev.'; '95% C.I.'};
            app.BestFitParamTable.RowName = {};
            app.BestFitParamTable.ColumnSortable = [true true false false false false false false false];
            app.BestFitParamTable.CellSelectionCallback = createCallbackFcn(app, @BestFitParamTableCellSelection, true);
            app.SaveToFileButton_5 = uibutton(app.BestFitTab, 'push');
            app.SaveToFileButton_5.ButtonPushedFcn = createCallbackFcn(app, @SaveToFileButton_5Pushed, true);
            app.SaveToFileButton_5.Enable = 'off';
            app.SaveToFileButton_5.Tooltip = {'Save parameters below to .csv file'};
            app.SaveToFileButton_5.Text = 'Save To File';
            app.SimulationTab = uitab(app.model_parameters_tabs);
            app.SimulationTab.Title = 'Simulation';
            app.SimParamTable = uitable(app.SimulationTab);
            app.SimParamTable.ColumnName = {'Field Name'; 'Type'; 'Value'; 'Lower Bound'; 'Upper Bound'; 'Units'; 'Plot Label'; 'Std. Dev.'; '95% C.I.'};
            app.SimParamTable.RowName = {};
            app.SimParamTable.ColumnSortable = [true true false false false false false false false];
            app.SimParamTable.ColumnEditable = [false true true true true false false false false];
            app.SimParamTable.CellEditCallback = createCallbackFcn(app, @SimParamTableCellEdit, true);
            app.SimParamTable.CellSelectionCallback = createCallbackFcn(app, @SimParamTableCellSelection, true);
            app.CopyFromModelButton = uibutton(app.SimulationTab, 'push');
            app.CopyFromModelButton.ButtonPushedFcn = createCallbackFcn(app, @CopyFromModelButtonPushed, true);
            app.CopyFromModelButton.Tooltip = {'Copy parameters from the "Model" table '};
            app.CopyFromModelButton.Text = 'Copy From Model';
            app.SimulateDataButton = uibutton(app.SimulationTab, 'push');
            app.SimulateDataButton.ButtonPushedFcn = createCallbackFcn(app, @push_simulate_button, true);
            app.SimulateDataButton.Enable = 'off';
            app.SimulateDataButton.Tooltip = {'Simulate data based on parameters below and values chosen in the "Simulate Axes" tab'};
            app.SimulateDataButton.Text = 'Simulate Data';
            app.LoadFromFileButton_4 = uibutton(app.SimulationTab, 'push');
            app.LoadFromFileButton_4.ButtonPushedFcn = createCallbackFcn(app, @LoadFromFileButton_4Pushed, true);
            app.LoadFromFileButton_4.Tooltip = {'Load parameters from a .csv file (requires proper formatting)'};
            app.LoadFromFileButton_4.Text = 'Load From File';
            app.SaveToFileButton_4 = uibutton(app.SimulationTab, 'push');
            app.SaveToFileButton_4.ButtonPushedFcn = createCallbackFcn(app, @SaveToFileButton_4Pushed, true);
            app.SaveToFileButton_4.Enable = 'off';
            app.SaveToFileButton_4.Tooltip = {'Save parameters below to .csv file'};
            app.SaveToFileButton_4.Text = 'Save To File';
            app.AddKuboButton_2 = uibutton(app.SimulationTab, 'push');
            app.AddKuboButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddKuboButton_2Pushed, true);
            app.AddKuboButton_2.Text = 'Add Kubo';
            app.RemoveKuboButton_2 = uibutton(app.SimulationTab, 'push');
            app.RemoveKuboButton_2.ButtonPushedFcn = createCallbackFcn(app, @RemoveKuboButton_2Pushed, true);
            app.RemoveKuboButton_2.Text = 'Remove Kubo';
            app.CompareSimulationOnlyTab = uitab(app.model_parameters_tabs);
            app.CompareSimulationOnlyTab.Title = 'Compare (Simulation Only)';
            app.CompareTable = uitable(app.CompareSimulationOnlyTab);
            app.CompareTable.ColumnName = {'Field Name'; 'Simulated (True) Value'; 'Predicted Fit Value'; 'Actual Fit Value'; '95% C.I.'; 'Units'};
            app.CompareTable.RowName = {};
            app.CompareTable.ColumnSortable = [true true false false false false];
            app.CompareTable.CellSelectionCallback = createCallbackFcn(app, @CompareTableCellSelection, true);
            app.Label = uilabel(app.CompareSimulationOnlyTab);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.CompareSimulationOnlyTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'The calculation of error bars reported with fitting parameters assumes that noise in the data maps linearly onto the fitting parameters. You may check this assumption empirically by fitting to simulated data where the global minimum is predicted using the simulated noise and the linear map.'};
            app.ModelFittingPanel = uipanel(app.ModelandFittingTab);
            app.ModelFittingPanel.Title = 'Model Fitting';
            app.StartFittingButton = uibutton(app.ModelFittingPanel, 'push');
            app.StartFittingButton.ButtonPushedFcn = createCallbackFcn(app, @push_start_fitting_button, true);
            app.StartFittingButton.Tooltip = {'Press to start model fitting'};
            app.StartFittingButton.Text = 'Start Fitting';
            app.PauseFittingButton = uibutton(app.ModelFittingPanel, 'state');
            app.PauseFittingButton.ValueChangedFcn = createCallbackFcn(app, @PauseFittingButtonValueChanged, true);
            app.PauseFittingButton.Tooltip = {'Press to pause model fitting'; 'Unpress to resume model fitting'};
            app.PauseFittingButton.Text = 'Pause Fitting';
            app.StopFittingButton = uibutton(app.ModelFittingPanel, 'state');
            app.StopFittingButton.ValueChangedFcn = createCallbackFcn(app, @StopFittingButtonValueChanged, true);
            app.StopFittingButton.Tooltip = {'Press to stop model fitting at the end of current the current iteration Please be patient - the program will notify you when it''s stopped in the feedback box above'};
            app.StopFittingButton.Text = 'Stop Fitting';
            app.UpdateInAppPlotsAtEachIterationCheckBox = uicheckbox(app.ModelFittingPanel);
            app.UpdateInAppPlotsAtEachIterationCheckBox.Tooltip = {'Update all plots at every fitting iteration.'; 'Uncheck for faster performance.'};
            app.UpdateInAppPlotsAtEachIterationCheckBox.Text = 'Update In-App Plots At Each Iteration';
            app.CPU_GPU_switch = uiswitch(app.ModelandFittingTab, 'toggle');
            app.CPU_GPU_switch.Items = {'CPU', 'GPU'};
            app.CPU_GPU_switch.Enable = 'off';
            app.CPU_GPU_switch.Tooltip = {'Toggle up for GPU computing.'; 'Toggle down for CPU computing'};
            app.CPU_GPU_switch.Value = 'CPU';
            app.SimulationAxesTab = uitab(app.TabGroup);
            app.SimulationAxesTab.Title = 'Simulation Axes';
            app.PumpAxisPanel = uipanel(app.SimulationAxesTab);
            app.PumpAxisPanel.Title = 'Pump Axis';
            app.StepSizepsSpinnerLabel = uilabel(app.PumpAxisPanel);
            app.StepSizepsSpinnerLabel.Text = 'Step Size (ps)';
            app.StepSizepsSpinner = uispinner(app.PumpAxisPanel);
            app.StepSizepsSpinner.ValueChangingFcn = createCallbackFcn(app, @StepSizepsSpinnerValueChanging, true);
            app.StepSizepsSpinner.ValueChangedFcn = createCallbackFcn(app, @StepSizepsSpinnerValueChanged, true);
            app.StepSizepsSpinner.Tooltip = {'Time step along pump axis'};
            app.StepSizepsSpinner.Value = 0.2;
            app.RotatingFramecm1SpinnerLabel = uilabel(app.PumpAxisPanel);
            app.RotatingFramecm1SpinnerLabel.Text = 'Rotating Frame (cm-1)';
            app.RotatingFramecm1Spinner = uispinner(app.PumpAxisPanel);
            app.RotatingFramecm1Spinner.ValueChangingFcn = createCallbackFcn(app, @RotatingFramecm1SpinnerValueChanging, true);
            app.RotatingFramecm1Spinner.ValueChangedFcn = createCallbackFcn(app, @RotatingFramecm1SpinnerValueChanged, true);
            app.RotatingFramecm1Spinner.Tooltip = {'Starting frequency of pump axis'};
            app.RotatingFramecm1Spinner.Value = 2100;
            app.NoPumpAxisPointsLabel = uilabel(app.PumpAxisPanel);
            app.NoPumpAxisPointsLabel.Text = 'No. Pump Axis Points';
            app.NoPumpPointsSpinner = uispinner(app.PumpAxisPanel);
            app.NoPumpPointsSpinner.ValueChangingFcn = createCallbackFcn(app, @NoPumpPointsSpinnerValueChanging, true);
            app.NoPumpPointsSpinner.ValueChangedFcn = createCallbackFcn(app, @NoPumpPointsSpinnerValueChanged, true);
            app.NoPumpPointsSpinner.Tooltip = {'Number of points along pump axis'};
            app.NoPumpPointsSpinner.Value = 16;
            app.Startcm1EditFieldLabel = uilabel(app.PumpAxisPanel);
            app.Startcm1EditFieldLabel.Text = 'Start (cm-1)';
            app.Startcm1EditField = uieditfield(app.PumpAxisPanel, 'numeric');
            app.Startcm1EditField.Editable = 'off';
            app.Endcm1EditFieldLabel = uilabel(app.PumpAxisPanel);
            app.Endcm1EditFieldLabel.Text = 'End (cm-1)';
            app.Endcm1EditField = uieditfield(app.PumpAxisPanel, 'numeric');
            app.Endcm1EditField.Editable = 'off';
            app.StepSizecm1EditFieldLabel = uilabel(app.PumpAxisPanel);
            app.StepSizecm1EditFieldLabel.Text = 'Step Size (cm-1)';
            app.StepSizecm1EditField = uieditfield(app.PumpAxisPanel, 'numeric');
            app.StepSizecm1EditField.Editable = 'off';
            app.ProbeAxisPanel = uipanel(app.SimulationAxesTab);
            app.ProbeAxisPanel.Title = 'Probe Axis';
            app.Startcm1SpinnerLabel = uilabel(app.ProbeAxisPanel);
            app.Startcm1SpinnerLabel.Text = 'Start (cm-1)';
            app.Startcm1Spinner = uispinner(app.ProbeAxisPanel);
            app.Startcm1Spinner.ValueChangingFcn = createCallbackFcn(app, @changing_value_start_probe_axis, true);
            app.Startcm1Spinner.ValueChangedFcn = createCallbackFcn(app, @changed_value_start_probe_axis, true);
            app.Startcm1Spinner.Tooltip = {'Starting frequency of probe axis'};
            app.Startcm1Spinner.Value = 2100;
            app.Endcm1SpinnerLabel = uilabel(app.ProbeAxisPanel);
            app.Endcm1SpinnerLabel.Text = 'End (cm-1)';
            app.Endcm1Spinner = uispinner(app.ProbeAxisPanel);
            app.Endcm1Spinner.ValueChangingFcn = createCallbackFcn(app, @changing_value_end_probe_axis, true);
            app.Endcm1Spinner.ValueChangedFcn = createCallbackFcn(app, @changed_value_end_probe_axis, true);
            app.Endcm1Spinner.Tooltip = {'Final frequency of probe axis'};
            app.Endcm1Spinner.Value = 2200;
            app.NoProbePointsLabel = uilabel(app.ProbeAxisPanel);
            app.NoProbePointsLabel.Text = 'No. Probe Points';
            app.NoProbePointsSpinner = uispinner(app.ProbeAxisPanel);
            app.NoProbePointsSpinner.ValueChangingFcn = createCallbackFcn(app, @changing_value_No_Probe_Points, true);
            app.NoProbePointsSpinner.ValueChangedFcn = createCallbackFcn(app, @changed_value_No_Probe_Points, true);
            app.NoProbePointsSpinner.Tooltip = {'Number of points along probe axis'};
            app.NoProbePointsSpinner.Value = 128;
            app.RotatingFramecm1EditFieldLabel = uilabel(app.ProbeAxisPanel);
            app.RotatingFramecm1EditFieldLabel.Text = 'Rotating Frame (cm-1)';
            app.RotatingFramecm1EditField = uieditfield(app.ProbeAxisPanel, 'numeric');
            app.RotatingFramecm1EditField.Editable = 'off';
            app.StepSizecm1EditField_3Label = uilabel(app.ProbeAxisPanel);
            app.StepSizecm1EditField_3Label.Text = 'Step Size (cm-1)';
            app.StepSizecm1EditField_3 = uieditfield(app.ProbeAxisPanel, 'numeric');
            app.StepSizecm1EditField_3.Editable = 'off';
            app.StepSizepsEditFieldLabel = uilabel(app.ProbeAxisPanel);
            app.StepSizepsEditFieldLabel.Text = 'Step Size (ps)';
            app.StepSizepsEditField = uieditfield(app.ProbeAxisPanel, 'numeric');
            app.StepSizepsEditField.Editable = 'off';
            app.SimErrorBox = uitextarea(app.SimulationAxesTab);
            app.WaitingTimeAxisPanel = uipanel(app.SimulationAxesTab);
            app.WaitingTimeAxisPanel.Title = 'Waiting Time Axis';
            app.SimTwInvVarTable = uitable(app.WaitingTimeAxisPanel);
            app.SimTwInvVarTable.ColumnName = {'Tw (ps)'; 'Inv.Var.'};
            app.SimTwInvVarTable.RowName = {};
            app.SimTwInvVarTable.ColumnEditable = true;
            app.SimTwInvVarTable.CellEditCallback = createCallbackFcn(app, @SimTwInvVarTableCellEdit, true);
            app.SimTwInvVarTable.CellSelectionCallback = createCallbackFcn(app, @SimTwInvVarTableCellSelection, true);
            app.SimTwInvVarTable.DisplayDataChangedFcn = createCallbackFcn(app, @SimTwInvVarTableDisplayDataChanged, true);
            app.AddTwPointButton = uibutton(app.WaitingTimeAxisPanel, 'push');
            app.AddTwPointButton.ButtonPushedFcn = createCallbackFcn(app, @AddTwPointButtonPushed, true);
            app.AddTwPointButton.Tooltip = {'Add a waiting time point to the series below'};
            app.AddTwPointButton.Text = 'Add Tw Point';
            app.DeleteTwPointButton = uibutton(app.WaitingTimeAxisPanel, 'push');
            app.DeleteTwPointButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteTwPointButtonPushed, true);
            app.DeleteTwPointButton.Tooltip = {'Delete the selected waiting time point from the series below'};
            app.DeleteTwPointButton.Text = 'Delete Tw Point';
            app.NoTwPointsEditFieldLabel = uilabel(app.WaitingTimeAxisPanel);
            app.NoTwPointsEditFieldLabel.Text = 'No. Tw Points';
            app.NoSimTwPoints = uieditfield(app.WaitingTimeAxisPanel, 'numeric');
            app.NoSimTwPoints.Editable = 'off';
            app.NoSimTwPoints.Value = 1;
            app.LoadTwAxis = uibutton(app.WaitingTimeAxisPanel, 'push');
            app.LoadTwAxis.ButtonPushedFcn = createCallbackFcn(app, @LoadTwAxisButtonPushed, true);
            app.LoadTwAxis.Tooltip = {'Add a waiting time point to the series below'};
            app.LoadTwAxis.Text = 'Load From File';
            app.SaveTwAxis = uibutton(app.WaitingTimeAxisPanel, 'push');
            app.SaveTwAxis.ButtonPushedFcn = createCallbackFcn(app, @SaveTwAxisButtonPushed, true);
            app.SaveTwAxis.Tooltip = {'Delete the selected waiting time point from the series below'};
            app.SaveTwAxis.Text = 'Save To File';
            app.OtherPanel = uipanel(app.SimulationAxesTab);
            app.OtherPanel.Title = 'Other';
            app.NumericTypeDropDownLabel = uilabel(app.OtherPanel);
            app.NumericTypeDropDownLabel.Text = 'Numeric Type';
            app.NumericTypeDropDown = uidropdown(app.OtherPanel);
            app.NumericTypeDropDown.Items = {'Real-Valued', 'Complex-Valued'};
            app.NumericTypeDropDown.Tooltip = {'Choose real-valued or complex-valued FID. Imaginary part of FID currently unavailable for viewing.'};
            app.NumericTypeDropDown.Value = 'Real-Valued';
            app.GaussianNoiseStdDevLabel = uilabel(app.OtherPanel);
            app.GaussianNoiseStdDevLabel.Text = 'Gaussian Noise (Std. Dev.)';
            app.GaussianNoiseStdDevSpinner = uispinner(app.OtherPanel);
            app.GaussianNoiseStdDevSpinner.Limits = [0 Inf];
            app.GaussianNoiseStdDevSpinner.Tooltip = {'Standard deviation of Gaussian noise added to simulated spectra'};
            app.GaussianNoiseStdDevSpinner.Value = 1e-05;
            app.CopyFromDataButton = uibutton(app.SimulationAxesTab, 'push');
            app.CopyFromDataButton.ButtonPushedFcn = createCallbackFcn(app, @CopyFromDataButtonPushed, true);
            app.CopyFromDataButton.Text = 'Copy From Data';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.DTab = uitab(app.TabGroup2);
            app.DTab.Title = '2D';
            app.Data2DPlot = uiaxes(app.DTab);
            title(app.Data2DPlot, 'Data')
            xlabel(app.Data2DPlot, 'Pump (cm^{-1})')
            ylabel(app.Data2DPlot, 'Probe (cm^{-1})')
            app.Model2DPlot = uiaxes(app.DTab);
            title(app.Model2DPlot, 'Model')
            xlabel(app.Model2DPlot, 'Pump (cm^{-1})')
            ylabel(app.Model2DPlot, 'Probe (cm^{-1})')
            app.Residual2DPlot = uiaxes(app.DTab);
            title(app.Residual2DPlot, 'Residual (Data - Model)')
            xlabel(app.Residual2DPlot, 'Pump (cm^{-1})')
            ylabel(app.Residual2DPlot, 'Probe (cm^{-1})')
            app.Tw_label = uilabel(app.DTab);
            app.Tw_label.Text = 'Tw';
            app.Tw_dd = uidropdown(app.DTab);
            app.Tw_dd.ValueChangedFcn = createCallbackFcn(app, @Tw_ddValueChanged, true);
            app.Tw_dd.Tooltip = {'Select a waiting time for viewing the 2D plots'};
            app.ProbeMinLabel = uilabel(app.DTab);
            app.ProbeMinLabel.Text = 'Probe Min.';
            app.ProbeMin = uispinner(app.DTab);
            app.ProbeMin.ValueChangedFcn = createCallbackFcn(app, @ProbeMinValueChanged, true);
            app.ProbeMin.Tooltip = {'Lower bound for plotting along the probe axis'};
            app.ProbeMaxLabel = uilabel(app.DTab);
            app.ProbeMaxLabel.Text = 'Probe Max.';
            app.ProbeMax = uispinner(app.DTab);
            app.ProbeMax.ValueChangedFcn = createCallbackFcn(app, @ProbeMaxValueChanged, true);
            app.ProbeMax.Tooltip = {'Upper bound for plotting along the probe axis'};
            app.PumpMinLabel = uilabel(app.DTab);
            app.PumpMinLabel.Text = 'Pump Min.';
            app.PumpMin = uispinner(app.DTab);
            app.PumpMin.ValueChangedFcn = createCallbackFcn(app, @PumpMinValueChanged, true);
            app.PumpMin.Tooltip = {'Lower bound for plotting along the pump axis'};
            app.PumpMaxLabel = uilabel(app.DTab);
            app.PumpMaxLabel.Text = 'Pump Max.';
            app.PumpMax = uispinner(app.DTab);
            app.PumpMax.ValueChangedFcn = createCallbackFcn(app, @PumpMaxValueChanged, true);
            app.PumpMax.Tooltip = {'Upper bound for plotting along the pump axis'};
            app.DTab_2 = uitab(app.TabGroup2);
            app.DTab_2.Title = '1D';
            app.FID_probe_axis_plot = uiaxes(app.DTab_2);
            title(app.FID_probe_axis_plot, 'FID Probe Axis Slice')
            xlabel(app.FID_probe_axis_plot, 'Probe')
            ylabel(app.FID_probe_axis_plot, '\DeltaOD')
            app.FID_pump_axis_plot = uiaxes(app.DTab_2);
            title(app.FID_pump_axis_plot, 'FID Pump Axis Slice')
            xlabel(app.FID_pump_axis_plot, 'Pump-Pair Delay (ps)')
            ylabel(app.FID_pump_axis_plot, '\DeltaOD')
            app.TwLabel = uilabel(app.DTab_2);
            app.TwLabel.Text = 'Tw';
            app.FID_probe_axis_Tw_dd = uidropdown(app.DTab_2);
            app.FID_probe_axis_Tw_dd.ValueChangedFcn = createCallbackFcn(app, @FID_probe_axis_Tw_ddValueChanged, true);
            app.FID_probe_axis_Tw_dd.Tooltip = {'Select a waiting time for viewing the probe slice'};
            app.t1Label = uilabel(app.DTab_2);
            app.t1Label.Text = 't1';
            app.FID_probe_axis_t1_dd = uidropdown(app.DTab_2);
            app.FID_probe_axis_t1_dd.ValueChangedFcn = createCallbackFcn(app, @FID_probe_axis_t1_ddValueChanged, true);
            app.FID_probe_axis_t1_dd.Tooltip = {'Select a coherence time for viewing the probe slice'};
            app.FID_Tw_label = uilabel(app.DTab_2);
            app.FID_Tw_label.Text = 'Tw';
            app.FID_pump_axis_Tw_dd = uidropdown(app.DTab_2);
            app.FID_pump_axis_Tw_dd.ValueChangedFcn = createCallbackFcn(app, @FID_pump_axis_Tw_ddValueChanged, true);
            app.FID_pump_axis_Tw_dd.Tooltip = {'Select a waiting time for viewing the pump slice'};
            app.FID_w3_label = uilabel(app.DTab_2);
            app.FID_w3_label.Text = 'w3';
            app.FID_pump_axis_w3_dd = uidropdown(app.DTab_2);
            app.FID_pump_axis_w3_dd.ValueChangedFcn = createCallbackFcn(app, @FID_pump_axis_w3_ddValueChanged, true);
            app.FID_pump_axis_w3_dd.Tooltip = {'Select a probe frequency for viewing the pump slice'};
            app.VIFsTab = uitab(app.TabGroup2);
            app.VIFsTab.Title = 'VIFs';
            app.VIF_plot = uiaxes(app.VIFsTab);
            title(app.VIF_plot, 'Variance Inflation Factors')
            xlabel(app.VIF_plot, '')
            ylabel(app.VIF_plot, 'VIFs')
            app.ProgramUpdatingLampLabel = uilabel(app.UIFigure);
            app.ProgramUpdatingLampLabel.Text = 'Program Updating...';
            app.ProgramUpdatingLamp = uilamp(app.UIFigure);
            app.ProgramUpdatingLamp.Tooltip = {'Program is thinking... please be patient.'};
            app.FeedbackBox = uitextarea(app.UIFigure);
            app.FeedbackBox.Editable = 'off';
            app.ClearFeedbackButton = uibutton(app.UIFigure, 'push');
            app.ClearFeedbackButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFeedbackButtonPushed, true);
            app.ClearFeedbackButton.Tooltip = {'Clear all text in the Feedback Box.'};
            app.ClearFeedbackButton.Text = 'Clear Feedback';
            app.FeedbackBoxLabel = uilabel(app.UIFigure);
            app.FeedbackBoxLabel.Text = 'Feedback Box';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Model_Fitting_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end